classdef FRET_Software < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FRETControlUIFigure  matlab.ui.Figure
        DataMenu             matlab.ui.container.Menu
        NewMenu              matlab.ui.container.Menu
        LoadMenu             matlab.ui.container.Menu
        SaveMenu             matlab.ui.container.Menu
        CloseMenu            matlab.ui.container.Menu
        SettingsMenu_2       matlab.ui.container.Menu
        SettingsMenu         matlab.ui.container.Menu
        CheckConnectionMenu  matlab.ui.container.Menu
        SetYAxesMenu         matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        Information_Area     matlab.ui.control.TextArea
        GridLayout3          matlab.ui.container.GridLayout
        EditField_MeasPoint  matlab.ui.control.NumericEditField
        DataPointsLabel      matlab.ui.control.Label
        EditField_DataTime   matlab.ui.control.NumericEditField
        FrequencyLabel       matlab.ui.control.Label
        EditField_Time       matlab.ui.control.NumericEditField
        TimeLabel            matlab.ui.control.Label
        Label5               matlab.ui.control.Label
        EditField_Mean6      matlab.ui.control.NumericEditField
        EditField_2Label_6   matlab.ui.control.Label
        EditField_Mean4      matlab.ui.control.NumericEditField
        EditField_2Label_5   matlab.ui.control.Label
        EditField_Mean2      matlab.ui.control.NumericEditField
        EditField_2Label_4   matlab.ui.control.Label
        EditField_Mean5      matlab.ui.control.NumericEditField
        EditField_2Label_3   matlab.ui.control.Label
        EditField_Mean3      matlab.ui.control.NumericEditField
        EditField_2Label_2   matlab.ui.control.Label
        EditField_Mean1      matlab.ui.control.NumericEditField
        MeanSignalIn1Label   matlab.ui.control.Label
        LineV_1              matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        ResizeAllButton      matlab.ui.control.Button
        ResizeButton         matlab.ui.control.Button
        GridLayout8          matlab.ui.container.GridLayout
        YminLabel            matlab.ui.control.Label
        EditFieldYmin        matlab.ui.control.NumericEditField
        GridLayout7          matlab.ui.container.GridLayout
        YmaxLabel            matlab.ui.control.Label
        EditFieldYmax        matlab.ui.control.NumericEditField
        GridLayout6          matlab.ui.container.GridLayout
        XmaxLabel_2          matlab.ui.control.Label
        EditFieldXmax        matlab.ui.control.NumericEditField
        GridLayout5          matlab.ui.container.GridLayout
        XminLabel            matlab.ui.control.Label
        EditFieldXmin        matlab.ui.control.NumericEditField
        DrawAllButton        matlab.ui.control.Button
        ClearAllButton       matlab.ui.control.Button
        DrawButton           matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        ClearButton          matlab.ui.control.Button
        Label4               matlab.ui.control.Label
        StopButton           matlab.ui.control.Button
        GoButton             matlab.ui.control.Button
        LineH_2              matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Console              matlab.ui.control.EditField
        PlotArea_6           matlab.ui.container.Panel
        PlotArea_5           matlab.ui.container.Panel
        PlotArea_4           matlab.ui.container.Panel
        PlotArea_3           matlab.ui.container.Panel
        PlotArea_2           matlab.ui.container.Panel
        PlotArea_1           matlab.ui.container.Panel
    end


    properties (Access = public)
        %%Base GUI properties%%
        hAx matlab.graphics.axis.Axes % Axes for animatedlines
        hAnimatedLine matlab.graphics.animation.AnimatedLine % animatedlines

        %%MLFI connection properties%%
        IP_adress;
        device;
        port;
        API_Level;
        demods;
        sizeDataCell;


        %%MLFI amplifier properties
        frequencies;
        transfer_rate;
        lowPassFilters;
        demodInput;
        inputSignalNames = ["Sig Vol In 1" "Sig Curr In 1"]
        demodOscis;
        waveLengthLED;


        %%Plot properties%%
        AxesLimits_Plot;
        AxesLimits_Plot_BackUp = repmat([0 1000 0 0.00001],6,1);
        AxesLabelUnits = ['V' 'A']
        plot_colors;
        ratioDemods1;
        ratioDemods2;
        AxesLabels = ["" "" "" "" "" ""];

        %%GUI properties%%
        selectedList;
        plotNames;

        %%Data%%
        measurementPlotData;
        measurementOrigData;
        lowDataMode;


        %%MultiWindow%%
        SettingsApp
        LEDControl_FRET

        %%Other
        measurementFlag = false;
        appName = 'FRETControl'
        Settings;
        isFresh = true;


    end

    methods (Access = private)

        function [hAx, hAnimatedLine]  = initializePlotAreas(app)
            hAx = createAxes(app);
            hAx = prepareAxes (app,hAx);
            hAnimatedLine = createAnimatedLines(app, hAx);
            transferAxes(app, hAx);
            pause(1);
            %arrayfun(@(x) updateAxesLimits(app, x, [0 1000 0 0.00001]), 1:6);
            arrayfun(@(x) updatePlotAxesLimits(app, x),  1:6);
            arrayfun(@(x) updateAxesfields(app, x),  1:6);
            transferAnimatedLines(app, hAnimatedLine);
            changeAnimatedLinesColor(app);

        end

        function hAx = createAxes(app)
            hAx(1) = axes(app.PlotArea_1);
            hAx(2) = axes(app.PlotArea_2);
            hAx(3) = axes(app.PlotArea_3);
            hAx(4) = axes(app.PlotArea_4);
            hAx(5) = axes(app.PlotArea_5);
            hAx(6) = axes(app.PlotArea_6);
            writeToConsole(app, "Axes were created!")
        end

        function hAx = prepareAxes(app,hAx)
            linkaxes(hAx);
            for i = 1:6
                hAx(i).Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                hAx(i).XAxis.Label.String = "Time (s)";
            end
            for i = 1:4
                hAx(i).YAxis.Label.String = "";
            end
            for i = 5:6
                hAx(i).YAxis.Label.String = "Ratio";
            end
            %   hAx(1).Toolbar = [];
            setappdata(hAx(1),'LegendColorbarManualSpace',1);
            setappdata(hAx(1),'LegendColorbarReclaimSpace',1);
            linkaxes(hAx, 'off');
            writeToConsole(app, "Axes were prepared!");
        end

        function transferAxes(app, hAx)
            app.hAx(1) = hAx(1);
            app.hAx(2) = hAx(2);
            app.hAx(3) = hAx(3);
            app.hAx(4) = hAx(4);
            app.hAx(5) = hAx(5);
            app.hAx(6) = hAx(6);
            writeToConsole(app, "Axes were copied to app!");
        end

        function hAnimatedLine = createAnimatedLines(app, hAx)
            hAnimatedLine(1) = animatedline(hAx(1),'MaximumNumPoints',1e8);
            hAnimatedLine(2) = animatedline(hAx(2),'MaximumNumPoints',1e8);
            hAnimatedLine(3) = animatedline(hAx(3),'MaximumNumPoints',1e8);
            hAnimatedLine(4) = animatedline(hAx(4),'MaximumNumPoints',1e8);
            hAnimatedLine(5) = animatedline(hAx(5),'MaximumNumPoints',1e8);
            hAnimatedLine(6) = animatedline(hAx(6),'MaximumNumPoints',1e8);
            writeToConsole(app, "Animated lines were created!");
        end

        function transferAnimatedLines(app, hAnimatedLine)
            app.hAnimatedLine(1) = hAnimatedLine(1);
            app.hAnimatedLine(2) = hAnimatedLine(2);
            app.hAnimatedLine(3) = hAnimatedLine(3);
            app.hAnimatedLine(4) = hAnimatedLine(4);
            app.hAnimatedLine(5) = hAnimatedLine(5);
            app.hAnimatedLine(6) = hAnimatedLine(6);
            writeToConsole(app, "AnimatedLines were copied to app!");
        end

        function changeAnimatedLinesColor(app)
            reshapedColors = reshape(app.plot_colors.',3,[]);
            for i = 1:6
                app.hAnimatedLine(i).Color = reshapedColors(:,i);
            end
            writeToConsole(app, "Colors set!");

        end

        function updateMlfiConnectionProperties(app, IP_adress, device, port, API_Level, demods, sizeDataCell)
            app.IP_adress = IP_adress;
            app.device = device;
            app.port = port;
            app.API_Level = API_Level;
            app.demods = demods;
            app.sizeDataCell = sizeDataCell;
            updateInformationAreaConnection(app);
            writeToConsole(app, "MFLI connection properties updated!");
        end

        function updateMlfiAmplifierProperties(app, frequencies, transferRate, waveLengthLEDs, lowPassFilters, demodInput, demodOscis)
            app.frequencies = frequencies;
            app.transfer_rate = transferRate;
            app.lowPassFilters = lowPassFilters;
            app.demodInput = demodInput;
            app.waveLengthLED = waveLengthLEDs;
            app.plotNames = [cellfun(@(x) append(app.inputSignalNames(x(1))," - " ,num2str(app.frequencies(x(2)))," Hz | ", "LED: " , ...
                num2str(app.waveLengthLED(x(2)))," nm"), app.vars2NumCell(arrayfun(@str2double,demodInput)+1, arrayfun(@str2double,demodOscis)+1)).' "Ratio1" "Ratio2" ];
            ratioDemods1 = (arrayfun(@str2double,app.ratioDemods1));
            ratioDemods2 = (arrayfun(@str2double,app.ratioDemods2));
            app.plotNames(5) = append("Ratio1: ",app.plotNames(ratioDemods1(1)), " / ", app.plotNames(ratioDemods1(2)));
            app.plotNames(6) = append("Ratio2: ",app.plotNames(ratioDemods2(1)), " / ", app.plotNames(ratioDemods2(2)));

            app.demodOscis = demodOscis;
            writeToConsole(app, "MFLI amplifier properties updated!");
        end

        function updatePlotProperties(app, axesLimits, plotColors, ratioDemods1, ratioDemods2)
            axesLimits = struct2table(axesLimits);
            axesLimits = table2array(axesLimits);
            app.AxesLimits_Plot = axesLimits;
            app.AxesLimits_Plot_BackUp = axesLimits;
            app.plot_colors = plotColors;
            app.ratioDemods1 = ratioDemods1;
            app.ratioDemods2 = ratioDemods2;
            writeToConsole(app, "Plot properties updated!");
        end

        function updateOtherProperties(app, lowDataMode)
            app.lowDataMode = lowDataMode;
        end

        function transferMFLISettings(app)
            lowPassFilterData = table2array(struct2table(app.lowPassFilters));
            timeConstants = bsxfun(@app.calculateTimeConstant, lowPassFilterData(:,1),lowPassFilterData(:,2));

            ziDAQ('setInt', '/dev5094/demods/0/oscselect', 0);

            cellfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' num2str(x(1)) '/oscselect'], x(2)),app.vars2NumCell(app.demods, app.demodOscis));
            cellfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' num2str(x(1)) '/adcselect'], x(2)),app.vars2NumCell(app.demods, app.demodInput));
            cellfun(@(x) ziDAQ('setDouble', ['/' app.device '/demods/' num2str(x(1)) '/timeconstant'], x(2)),app.vars2NumCell(app.demods, timeConstants));
            cellfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' num2str(x(1)) '/order'], x(2)),app.vars2NumCell(app.demods, lowPassFilterData(:,2)));
            cellfun(@(x) ziDAQ('setDouble', ['/' app.device '/oscs/' num2str(x(1)) '/freq'], x(2)),app.vars2NumCell(app.demods, app.frequencies));
            cellfun(@(x) ziDAQ('setDouble', ['/' app.device '/demods/' num2str(x(1)) '/rate'], x(2)),app.vars2NumCell(app.demods,app.transfer_rate));

            writeToConsole(app, "MFLI amplifier properties transfered!");
        end

        function updateInformationAreaConnection(app)
            writeToInformationArea(app,append("IP Adress: ", app.IP_adress),1);
            writeToInformationArea(app,append("Device: ", app.device),1);
            writeToInformationArea(app,append("Port: ", num2str(app.port)),1);
            writeToInformationArea(app,append("Demodulatoren: ", append(app.demods)),1);
            writeToInformationArea(app,"",1);
        end

        function updateInformationAreaMemory(app)
            writeToInformationArea(app,append("Used Memory: ", num2str(app.GetAvailableMemory()), "%"),0);
        end

        function writeToInformationArea(app, text, newLine)
            if isnumeric(text)
                text = num2str(text);
            end
            app.Information_Area.Value{length(app.Information_Area.Value)+newLine} = convertStringsToChars(text);
        end

        function clearInformationArea(app)
            app.Information_Area.Value = '';
        end


        function switchStatusLamp(app, color)
            app.Lamp.Color = color;
        end

        function updateAxesLimits(app, plotNumber, limits)
            app.AxesLimits_Plot{plotNumber} = limits;
            writeToConsole(app, append("Plot limits for plot ",num2str(plotNumber)," prepared!"));
        end

        function updatePlotAxesLimits(app, plotNumber, backUp)
                        arguments
                app;
                plotNumber;
                backUp = false;
            end
            if backUp == false
            app.hAx(plotNumber).XLim = app.AxesLimits_Plot(plotNumber,1:2);
            app.hAx(plotNumber).YLim = app.AxesLimits_Plot(plotNumber,3:4);
            end
            if backUp == true
                            app.hAx(plotNumber).XLim = app.AxesLimits_Plot_BackUp(plotNumber,1:2);
            app.hAx(plotNumber).YLim = app.AxesLimits_Plot_BackUp(plotNumber,3:4);
            end
            switch true
                case ismember(floor(log10(app.AxesLimits_Plot(plotNumber,4))),-3:0)
                    app.hAx(plotNumber).YAxis.Exponent = -3;
                case ismember(floor(log10(app.AxesLimits_Plot(plotNumber,4))),-6:-4)
                    app.hAx(plotNumber).YAxis.Exponent = -6;
                case ismember(floor(log10(app.AxesLimits_Plot(plotNumber,4))),-9:-7)
                    app.hAx(plotNumber).YAxis.Exponent = -9;
            end


            app.Settings.Plot{1}(plotNumber).x = app.AxesLimits_Plot(plotNumber,1:2);
            app.Settings.Plot{1}(plotNumber).y = app.AxesLimits_Plot(plotNumber,3:4);
        end

        function updateAxesfields(app,NumAxes)
            app.EditFieldXmin.Value = app.AxesLimits_Plot(NumAxes,1);
            app.EditFieldXmax.Value = app.AxesLimits_Plot(NumAxes,2);
            app.EditFieldYmin.Value = app.AxesLimits_Plot(NumAxes,3);
            app.EditFieldYmin.ValueDisplayFormat = append('%5.5f  ', app.AxesLabels(NumAxes));
            app.EditFieldYmax.Value = app.AxesLimits_Plot(NumAxes,4);
            app.EditFieldYmax.ValueDisplayFormat = append('%5.5f  ', app.AxesLabels(NumAxes));
        end


        function renamePlotAreas(app)
            app.PlotArea_1.Title = app.plotNames(1);
            app.PlotArea_2.Title = app.plotNames(2);
            app.PlotArea_3.Title = app.plotNames(3);
            app.PlotArea_4.Title = app.plotNames(4);
            app.PlotArea_5.Title = app.plotNames(5);
            app.PlotArea_6.Title = app.plotNames(6);
            writeToConsole(app, "Plots renamed!");

        end

        function renameListItems(app)
            app.ListBox.Items = app.plotNames;
            writeToConsole(app, "List Items renamed!");

        end

        function updatePlotDataFields (app, meanValues, time, transferedDataPoints, frequency)

            arguments
                app
                meanValues = zeros(6,1)
                time = 0
                transferedDataPoints = 0
                frequency {mustBeNumeric} = 0
            end
            app.EditField_Mean1.Value = meanValues(1);
            app.EditField_Mean2.Value = meanValues(2);
            app.EditField_Mean3.Value = meanValues(3);
            app.EditField_Mean4.Value = meanValues(4);
            app.EditField_Mean5.Value = meanValues(5);
            app.EditField_Mean6.Value = meanValues(6);

            app.EditField_Time.Value = time;
            app.EditField_MeasPoint.Value = transferedDataPoints;
            app.EditField_DataTime.Value = frequency;
        end

        function clearPlots(app, buttonPushed)
            arguments
                app;
                buttonPushed = false;
            end
            for selectedList = 1:6
                if buttonPushed == true
                    app.AxesLimits_Plot(selectedList,:) = app.AxesLimits_Plot_BackUp(selectedList,:);
                end
                %transferAxes(app,prepareAxes(app, app.hAx));
                clearpoints(app.hAnimatedLine(selectedList));
                updateAxesfields(app, selectedList);
                updatePlotAxesLimits(app, selectedList);
                writeToConsole(app, "Plots cleared!");
            end
        end

        function startUpLoadSettings(app, fileName)
            arguments
                app;
                fileName = getStandardFilePath(app,"Settings/Startup");
            end
            files = dir(fileName);
            fileName = fullfile(fileName, files(3).name);
            Settings = load(fileName);
            names = fieldnames(Settings);
            app.Settings = Settings.(names{1});
            pause(1);
            writeToConsole(app, append("Settings loaded: ", fileName));
        end

        function clearData(app)
            app.measurementPlotData = 0;
            app.measurementOrigData = 0;
        end


        function disEnableButtons(app, status)
            app.ClearButton.Enable = status;
            app.ClearAllButton.Enable = status;
            app.DrawAllButton.Enable = status;
            app.DrawButton.Enable = status;
            app.ResizeButton.Enable = status;
            app.ResizeAllButton.Enable = status;
        end

        function renameAxes(app)
            for i = 1:4
                app.hAx(i).YAxis.Label.String = append("Intensity (", app.AxesLabels(i),")");
            end
            app.hAx(5).YAxis.Label.String = append("Ratio (", app.AxesLabels(5),")");
            app.hAx(6).YAxis.Label.String = append("Ratio (", app.AxesLabels(6),")");
        end

        function getPlotUnits(app)
            for i = 1:4
                app.AxesLabels(i) = app.AxesLabelUnits(str2double(app.demodInput(i))+1);
            end
            app.AxesLabels(5)  = append(app.AxesLabels(str2double(app.ratioDemods1(1))), "/", ...
                app.AxesLabels(str2double(app.ratioDemods1(2))));
            app.AxesLabels(6)  = append(app.AxesLabels(str2double(app.ratioDemods2(1))), "/", ...
                app.AxesLabels(str2double(app.ratioDemods2(2))));
        end
    end


    methods (Access = public)
        function filePathName = getStandardFilePath(app, folder)
            username=getenv('USERNAME');
            filePathName = fullfile('C:\Users\', username, '\Documents\',app.appName, folder);
        end

        function writeToConsole(app, message)
            if isstring(message)
                app.Console.Value = message;
            else
                app.Console.Value = "Message is not a string!";
            end
        end

        function checkConnection(app, IP_adress, port, device, API_Level)
            switchStatusLamp(app, "yellow")
            wb = waitbar(0, 'Checking Connection ...','WindowStyle', 'modal');
            wbch = allchild(wb);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1);
            try
                ziDAQ('connect', IP_adress, port, API_Level) % 8004 for MFLI (see below)`
                [~, ~] = ziCreateAPISession(device, API_Level);
                ziDAQ('sync');
                ziDAQ('subscribe', ['/' device '/demods/'])
                ziDAQ('unsubscribe', '*');
                writeToConsole(app, "Connection succesfully tested!")
                switchStatusLamp(app, "green")
            catch
                writeToConsole(app, "Connection test unsuccesfull!")
                switchStatusLamp(app, "red")
            end
            close(wb)
        end

        function updateSettings(app)
            updateMlfiConnectionProperties(app,  ...
                app.Settings.MFLI_Connection{1}, ...
                app.Settings.MFLI_Connection{2}, ...
                app.Settings.MFLI_Connection{3}, ...
                app.Settings.MFLI_Connection{4}, ...
                app.Settings.MFLI_Connection{5}, ...
                app.Settings.MFLI_Connection{6});
            updatePlotProperties(app, ...
                app.Settings.Plot{1}, ...
                app.Settings.Plot{2}, ...
                app.Settings.Plot{3}, ...
                app.Settings.Plot{4});
            updateMlfiAmplifierProperties(app,  ...
                app.Settings.MFLI_Amplifier{1}, ...
                app.Settings.MFLI_Amplifier{2}, ...
                app.Settings.MFLI_Amplifier{3}, ...
                app.Settings.MFLI_Amplifier{4}, ...
                app.Settings.MFLI_Amplifier{5}, ...
                app.Settings.MFLI_Amplifier{6});
            updateOtherProperties(app, ...
                app.Settings.Other{1});
            renameListItems(app);
            renamePlotAreas(app);
            getPlotUnits(app);
            renameAxes(app);
            changeAnimatedLinesColor(app);
            arrayfun(@(x) updatePlotAxesLimits(app,x),1:6);
        end

    end

    methods ( Static )

        function saveVariables(measurementPlotData, measurementOrigData, Settings, Information_Area)
            uisave({'measurementPlotData', 'measurementOrigData', 'Settings', 'Information_Area'}, string(datetime('now','Format','dd.MM.y-HH_mm_ss')));
        end

        function [measTime, transferedDataPoints, transferfrequency] = calculatePlotData(measurementPlotData)
            measTime = measurementPlotData(1).time(end);
            transferedDataPoints = length(measurementPlotData(1).time);
            transferfrequency = 1/(measTime/transferedDataPoints);
        end

        function timeConstant = calculateTimeConstant(bandwith, order)
            FO = [1 0.6436 0.5098 0.4350 0.3856 0.3499 0.3226 0.3008];
            fcutoff = bandwith;
            timeConstant = FO(order) ./ (2*pi*fcutoff).';
        end

        %         function cells = vars2NumCell(var1, var2)
        %             cells = cell(length(var1),1);
        %             if isstring(var1) || ischar(var1)
        %                 var1 = arrayfun(@str2num,var1);
        %             end
        %             if isstring(var2) || ischar(var2)
        %                 var2 = arrayfun(@str2num,var2);
        %             end
        %             for i = 1:length(var1)
        %                 cells{i} = [var1(i) var2(i)];
        %             end
        %         end

        function cells = vars2NumCell(varargin)
            for i = 1:nargin
                if isstring(varargin{i}) || ischar(varargin{i})
                    varargin{i} = arrayfun(@str2num,varargin{i});
                end
            end
            cells = cell(length(varargin{i}),1);

            for i = 1:length(varargin{i})
                cells{i} = cellfun(@(x) [x(i)], varargin);
            end
        end

        function availableMemory = GetAvailableMemory()
            [~,sys] = memory;
            availableMemory = 1 - sys.PhysicalMemory.Available / sys.PhysicalMemory.Total;
        end

        function output = bool2string(var)
            if var
                output = "true";
            else
                output = "false";
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed
                warning off
            end

            try
                mkdir(getStandardFilePath(app, "Settings"))
                mkdir(getStandardFilePath(app, "Settings/Startup"))
                mkdir(getStandardFilePath(app, "Data"))
                addpath(genpath("MyComponents\"))
                %
                addpath(genpath("C:\Program Files\Zurich Instruments\LabOne\API\MATLAB2012"))
            catch

            end
            pause(2);
            switchStatusLamp(app, "red");
            wb = waitbar(0, 'Starting ...','WindowStyle', 'modal');
            wbch = allchild(wb);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1);
            writeToInformationArea(app, string(datetime('now')), false);
            writeToInformationArea(app, getenv('USERNAME'), true);
            writeToInformationArea(app, repmat('-',1,80), true);
            waitbar(0,wb,'Loading settings...');
%            try
                startUpLoadSettings(app);
                updateMlfiConnectionProperties(app,  ...
                    app.Settings.MFLI_Connection{1}, ...
                    app.Settings.MFLI_Connection{2}, ...
                    app.Settings.MFLI_Connection{3}, ...
                    app.Settings.MFLI_Connection{4}, ...
                    app.Settings.MFLI_Connection{5}, ...
                    app.Settings.MFLI_Connection{6});
                updatePlotProperties(app, ...
                    app.Settings.Plot{1}, ...
                    app.Settings.Plot{2}, ...
                    app.Settings.Plot{3}, ...
                    app.Settings.Plot{4});

                updateMlfiAmplifierProperties(app,  ...
                    app.Settings.MFLI_Amplifier{1}, ...
                    app.Settings.MFLI_Amplifier{2}, ...
                    app.Settings.MFLI_Amplifier{3}, ...
                    app.Settings.MFLI_Amplifier{4}, ...
                    app.Settings.MFLI_Amplifier{5}, ...
                    app.Settings.MFLI_Amplifier{6});

                updateOtherProperties(app, ...
                    app.Settings.Other{1});
%            catch
%                writeToConsole(app, "Couldn't load Startup Settings file!")
%            end
            app.selectedList = app.ListBox.Value;
            waitbar(0,wb,'Initialize plot areas...');
            initializePlotAreas(app);
            renameListItems(app);
            renamePlotAreas(app);
            getPlotUnits(app);
            renameAxes(app);
            close(wb);
            switchStatusLamp(app, "green");


        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            switchStatusLamp(app, "yellow");
            app.measurementFlag = true;
            disEnableButtons(app, false);

            ziDAQ('connect', app.IP_adress, app.port, app.API_Level) % 8004 for MFLI (see below)`
            [~, ~] = ziCreateAPISession(app.device, app.API_Level);
            transferMFLISettings(app);
            %ziLoadSettings(app.device, 'my_settings.xml');
            %arrayfun(@(x) ziDAQ('setDouble', ['/' app.device '/demods/' x '/rate'], app.transfer_rate),app.demods);
            clockbase = double(ziDAQ('getInt', ['/' app.device '/clockbase']));
            arrayfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' x '/enable'], 1), app.demods);

            ziDAQ('setInt', ['/' app.device '/currins/0/autorange'], 1);
            pause(2);
            ziDAQ('setInt', ['/' app.device '/sigins/0/autorange'], 1);
            pause(2);
            ziDAQ('sync');
            ziDAQ('subscribe', ['/' app.device '/demods/'])
            pause(2);
            ziDAQpollData = ziDAQ('poll', 1, 500);
            writeToInformationArea(app, append("Low Data Mode: ", app.bool2string(app.lowDataMode)),true);
            writeToInformationArea(app, append("Measurement Started: ", string(datetime("now"),'HH:mm:SS')), true);
            writeToInformationArea(app, append(""), true);

            writeToConsole(app, "Working...");
            i = 1;
            transferedDataPoints =0;
            r_cor = cell(6,1);
            time = cell(6,1);
            ratioColumns = [arrayfun(@str2num,app.ratioDemods1) arrayfun(@str2num,app.ratioDemods2)];
            data = cell(4,app.sizeDataCell);
            t0 = ziDAQpollData.dev5094.demods(1).sample.timestamp(end);
            %tic
            %while etime(clock, timer0) < time_meas
            while app.measurementFlag == true

                ziDAQpollData = ziDAQ('poll', 0.5, 500);


                if(app.lowDataMode)
                    data(:,i) = arrayfun(@(x) rmfield(ziDAQpollData.(app.device).demods(x).sample, {'frequency', 'phase', 'bits', 'trigger', 'auxin0', 'auxin1', 'time'}),1:4,'UniformOutput', false);
                else
                    data(:,i) = arrayfun(@(x) struct(x.sample), ziDAQpollData.(app.device).demods, 'UniformOutput', false)';

                end


                r_cor(1:4) = cellfun(@(x) sqrt(x.x.^2 + x.y.^2), data(:,i), 'UniformOutput', false);
                lengths = (cellfun('length', r_cor));
                %         [minLength,~] = min(lengths(1:4));


                r_cor([ratioColumns(1) ratioColumns(2)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(1) ratioColumns(2)]))), r_cor([ratioColumns(1) ratioColumns(2)]), 'un', 0);
                r_cor([ratioColumns(3) ratioColumns(4)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(3) ratioColumns(4)]))), r_cor([ratioColumns(3) ratioColumns(4)]), 'un', 0);


                %           r_cor(1:4) = cellfun(@(x) x(1:minLength), r_cor(1:4), 'un', 0);
                try
                r_cor{5} = r_cor{ratioColumns(1)} ./ r_cor{ratioColumns(2)};
                r_cor{6} = r_cor{ratioColumns(3)} ./ r_cor{ratioColumns(4)};
                end

                time(1:4) = cellfun(@(x) (double(x.timestamp) - double(t0)) / clockbase, data(:,i), 'UniformOutput', false);

                time([ratioColumns(1) ratioColumns(2)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(1) ratioColumns(2)]))), time([ratioColumns(1) ratioColumns(2)]), 'un', 0);
                time([ratioColumns(3) ratioColumns(4)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(3) ratioColumns(4)]))), time([ratioColumns(3) ratioColumns(4)]), 'un', 0);

                %            time(1:4) = cellfun(@(x) x(1:minLength), time(1:4), 'un', 0);

                time{5} = time{ratioColumns(1)};
                time{6} = time{ratioColumns(3)};
                % Time für jeden Plot einzeln berechnen.
                meanValues = cellfun(@mean, cellfun(@rmmissing,r_cor,'UniformOutput', false));
                measTime = time{1}(end);
                transferedDataPoints = transferedDataPoints + length(time{1});
                transferfrequency = 1 / (measTime/transferedDataPoints);
                try
                updatePlotDataFields(app,meanValues,measTime, transferedDataPoints, transferfrequency);
                for num = 1:6
                    addpoints(app.hAnimatedLine(num), time{num},r_cor{num});
                end
                end
                i = i + 1;
                drawnow limitrate
                updateInformationAreaMemory(app);
            end
            %toc
            app.isFresh = false;
            measurementData(6,1) = struct("time",[], "r_cor", []);
            for i = 1:6
                [measurementData(i).time, measurementData(i).r_cor] = getpoints(app.hAnimatedLine(i));
            end

            app.measurementPlotData = measurementData;
            app.measurementOrigData = data;
            ziDAQ('unsubscribe', '*');
            disEnableButtons(app, true);
            writeToConsole(app, "Finished!");

            switchStatusLamp(app, "green");


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.selectedList = value;
            updateAxesfields(app, value);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            [var1, ~] =  getpoints(app.hAnimatedLine(app.selectedList));
            if isempty(var1) && app.isFresh == false
                updatePlotAxesLimits(app, app.selectedList);
                addpoints(app.hAnimatedLine(app.selectedList), app.measurementPlotData(app.selectedList).time, app.measurementPlotData(app.selectedList).r_cor)
                drawnow limitrate
                writeToConsole(app, append("Plot ",num2str(app.selectedList)," drawn!"));
            else
                updatePlotAxesLimits(app, app.selectedList);
            end

        end

        % Value changed function: EditFieldXmin
        function EditFieldXminValueChanged(app, event)
            value = app.EditFieldXmin.Value;
            app.AxesLimits_Plot(app.selectedList,1) = value;
        end

        % Value changed function: EditFieldXmax
        function EditFieldXmaxValueChanged(app, event)
            value = app.EditFieldXmax.Value;
            app.AxesLimits_Plot(app.selectedList,2) = value;
        end

        % Value changed function: EditFieldYmin
        function EditFieldYminValueChanged(app, event)
            value = app.EditFieldYmin.Value;
            app.AxesLimits_Plot(app.selectedList,3) = value;
        end

        % Value changed function: EditFieldYmax
        function EditFieldYmaxValueChanged(app, event)
            value = app.EditFieldYmax.Value;
            app.AxesLimits_Plot(app.selectedList,4) = value;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.AxesLimits_Plot(app.selectedList,:) = app.AxesLimits_Plot_BackUp(app.selectedList,:);
            clearpoints(app.hAnimatedLine(app.selectedList));
            updateAxesfields(app, app.selectedList);
            updatePlotAxesLimits(app, app.selectedList);
            writeToConsole(app, append("Plot ",num2str(app.selectedList)," cleared!"));

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.measurementFlag = false;
            switchStatusLamp(app, "green");
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            clearPlots(app);
        end

        % Button pushed function: DrawAllButton
        function DrawAllButtonPushed(app, event)
            [var1, ~] =  getpoints(app.hAnimatedLine(app.selectedList));
            if isempty(var1) && app.isFresh == false
                for selectedList = 1:6
                    updatePlotAxesLimits(app, selectedList);
                    addpoints(app.hAnimatedLine(selectedList), app.measurementPlotData(selectedList).time, app.measurementPlotData(selectedList).r_cor)
                    drawnow limitrate
                end
                writeToConsole(app, "Plots drawn!");

            else
                for selectedList = 1:6
                    updatePlotAxesLimits(app, selectedList);
                end
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.saveVariables(app.measurementPlotData, app.measurementOrigData, app.Settings, app.Information_Area.Value);
            writeToConsole(app, "Data saved!");

        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            wb = waitbar(0, 'Loading Data ...','WindowStyle', 'modal');
            app.Information_Area.Value = "";
            wbch = allchild(wb);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1);
            uiopen('*.mat');
            if ~isempty(measurementPlotData) && ~isempty(measurementOrigData)
                app.measurementPlotData = measurementPlotData;
                app.measurementOrigData = measurementOrigData;
                app.Settings = Settings;
                app.Information_Area.Value = Information_Area;
                clearPlots(app);
                app.ListBox.Value = 1;
                app.selectedList = 1;
                [measTime, transferedDataPoints, transferfrequency] = app.calculatePlotData(measurementPlotData);
                updatePlotDataFields(app,zeros(6,1),measTime,transferedDataPoints,transferfrequency);
                updatePlotProperties(app, Settings.Plot{1}, Settings.Plot{2}, Settings.Plot{3}, Settings.Plot{4})


                app.plotNames = [cellfun(@(x) append(app.inputSignalNames(x(1))," - " ,num2str(app.Settings.MFLI_Amplifier{1}(x(2)))," Hz | ", "LED: " , ...
                    num2str(app.Settings.MFLI_Amplifier{3}(x(2)))," nm"), app.vars2NumCell(arrayfun(@str2double,app.Settings.MFLI_Amplifier{5})+1, arrayfun(@str2double,app.Settings.MFLI_Amplifier{6})+1)).' "Ratio1" "Ratio2" ];
                ratioDemods1 = (arrayfun(@str2double,app.ratioDemods1));
                ratioDemods2 = (arrayfun(@str2double,app.ratioDemods2));
                app.plotNames(5) = append("Ratio1: ",app.plotNames(ratioDemods1(1)), " / ", app.plotNames(ratioDemods1(2)));
                app.plotNames(6) = append("Ratio2: ",app.plotNames(ratioDemods2(1)), " / ", app.plotNames(ratioDemods2(2)));
                arrayfun(@(x) updateAxesfields(app, x),  1:6);
                arrayfun(@(x) updatePlotAxesLimits(app, x),  1:6);
                renamePlotAreas(app);
                getPlotUnits(app);
                renameAxes(app);
                changeAnimatedLinesColor(app);
                app.isFresh = false;
                writeToConsole(app, "Data loaded!");
            else
                writeToConsole(app, "Data is empty - not loaded!");
            end
            close(wb);

        end

        % Menu selected function: CheckConnectionMenu
        function CheckConnectionMenuSelected(app, event)

            checkConnection(app, app.IP_adress, app.port, app.device, app.API_Level);

        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            app.SettingsMenu.Enable = false;
            app.SettingsApp = settings_window(app,app.Settings);
        end

        % Close request function: FRETControlUIFigure
        function FRETControlUIFigureCloseRequest(app, event)
            delete(app.SettingsApp)
            delete(app)

        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            msg = 'Do you really want to open a new measurement. All unsaved progress will be lost!';
title = 'Confirm New Measurement';
selection = uiconfirm(app.UIFigure,msg,title);
            if selection == "Cancel" 
            return 
            end
            clearPlots(app);
            clearData(app);
            clearInformationArea(app);
            app.isFresh = true;
            writeToConsole(app, "New Measurement!");
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            [time, intensity] =  getpoints(app.hAnimatedLine(app.selectedList));
            if ~(isempty(time) && app.isFresh == false)
                app.AxesLimits_Plot(app.selectedList,1:2) = [min(time) max(time)];
                app.AxesLimits_Plot(app.selectedList,3:4) = [min(intensity) max(intensity)];
                updatePlotAxesLimits(app, app.selectedList);
                updateAxesfields(app, app.selectedList);
            end
        end

        % Button pushed function: ResizeAllButton
        function ResizeAllButtonPushed(app, event)
            for selectedList = 1:6
                [time, intensity] =  getpoints(app.hAnimatedLine(selectedList));
                if ~(isempty(time) && app.isFresh == false)
                    app.AxesLimits_Plot(selectedList,1:2) = [min(time) max(time)];
                    app.AxesLimits_Plot(selectedList,3:4) = [min(intensity) max(intensity)];
                    updatePlotAxesLimits(app, selectedList);
                    updateAxesfields(app, selectedList);
                end
            end
            writeToConsole(app, "Plots resized");
        end

        % Menu selected function: SetYAxesMenu
        function SetYAxesMenuSelected(app, event)
            switchStatusLamp(app, "yellow");

            ziDAQ('connect', app.IP_adress, app.port, app.API_Level) % 8004 for MFLI (see below)`
            [~, ~] = ziCreateAPISession(app.device, app.API_Level);
            transferMFLISettings(app);
            %ziLoadSettings(app.device, 'my_settings.xml');
            %arrayfun(@(x) ziDAQ('setDouble', ['/' app.device '/demods/' x '/rate'], app.transfer_rate),app.demods);
            clockbase = double(ziDAQ('getInt', ['/' app.device '/clockbase']));
            arrayfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' x '/enable'], 1), app.demods);
            ratioColumns = [arrayfun(@str2num,app.ratioDemods1) arrayfun(@str2num,app.ratioDemods2)];

            ziDAQ('setInt', ['/' app.device '/currins/0/autorange'], 1);
            pause(3);
            ziDAQ('setInt', ['/' app.device '/sigins/0/autorange'], 1);
            ziDAQ('sync');
            ziDAQ('subscribe', ['/' app.device '/demods/'])
            pause(3);
            ziDAQpollData = ziDAQ('poll', 1, 500);
            data(:,1) = arrayfun(@(x) struct(x.sample), ziDAQpollData.(app.device).demods, 'UniformOutput', false)';

            r_cor(1:4) = cellfun(@(x) sqrt(x.x.^2 + x.y.^2), data(:,1), 'UniformOutput', false);
            lengths = (cellfun('length', r_cor));
            %         [minLength,~] = min(lengths(1:4));


            r_cor([ratioColumns(1) ratioColumns(2)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(1) ratioColumns(2)]))), r_cor([ratioColumns(1) ratioColumns(2)]), 'un', 0);
            r_cor([ratioColumns(3) ratioColumns(4)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(3) ratioColumns(4)]))), r_cor([ratioColumns(3) ratioColumns(4)]), 'un', 0);


            %           r_cor(1:4) = cellfun(@(x) x(1:minLength), r_cor(1:4), 'un', 0);
            r_cor{5} = r_cor{ratioColumns(1)} ./ r_cor{ratioColumns(2)};
            r_cor{6} = r_cor{ratioColumns(3)} ./ r_cor{ratioColumns(4)};


            minValues = cellfun(@min, cellfun(@rmmissing,r_cor,'UniformOutput', false));
            maxValues = cellfun(@max, cellfun(@rmmissing,r_cor,'UniformOutput', false));


            for selectedList = 1:6
                minMax(1:2) = [minValues(selectedList)*0.2 maxValues(selectedList)*2];
                app.AxesLimits_Plot(selectedList,3:4) = minMax;
                updatePlotAxesLimits(app, selectedList);
                updateAxesfields(app, selectedList);
            end
            ziDAQ('unsubscribe', '*');
            switchStatusLamp(app, "green");

        end

        % Callback function
        function SetIntensityMenuSelected(app, event)
            switchStatusLamp(app, "yellow");

            ziDAQ('connect', app.IP_adress, app.port, app.API_Level) % 8004 for MFLI (see below)`
            [~, ~] = ziCreateAPISession(app.device, app.API_Level);
            transferMFLISettings(app);
            %ziLoadSettings(app.device, 'my_settings.xml');
            %arrayfun(@(x) ziDAQ('setDouble', ['/' app.device '/demods/' x '/rate'], app.transfer_rate),app.demods);
            arrayfun(@(x) ziDAQ('setInt', ['/' app.device '/demods/' x '/enable'], 1), app.demods);
            ratioColumns = [arrayfun(@str2num,app.ratioDemods1) arrayfun(@str2num,app.ratioDemods2)];

            ziDAQ('setInt', ['/' app.device '/currins/0/autorange'], 1);
            pause(2);
            ziDAQ('setInt', ['/' app.device '/sigins/0/autorange'], 1);
            ziDAQ('sync');
            pause(2);
            ziDAQ('subscribe', ['/' app.device '/demods/'])
            pause(2);
            ziDAQpollData = ziDAQ('poll', 5, 500);
            ziDAQ('unsubscribe', '*');
            data(:,1) = arrayfun(@(x) struct(x.sample), ziDAQpollData.(app.device).demods, 'UniformOutput', false)';
            r_cor(1:4) = cellfun(@(x) sqrt(x.x.^2 + x.y.^2), data(:,1), 'UniformOutput', false);
            lengths = (cellfun('length', r_cor));
            r_cor([ratioColumns(1) ratioColumns(2)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(1) ratioColumns(2)]))), r_cor([ratioColumns(1) ratioColumns(2)]), 'un', 0);
            r_cor([ratioColumns(3) ratioColumns(4)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(3) ratioColumns(4)]))), r_cor([ratioColumns(3) ratioColumns(4)]), 'un', 0);
            r_cor = cellfun(@rmmissing,r_cor,'UniformOutput', false);
            r_cor = cellfun(@(x) normalize(x,'range'),r_cor,'UniformOutput', false);

            r_cor_smooth = cellfun(@(x) smoothdata(x, 'movmedian', 100),r_cor,'UniformOutput', false);
            r_cor_smooth = r_cor_smooth{1};
            len = length(r_cor_smooth);
            r_cor_smooth =  mean(r_cor_smooth(1: 0.2 * len)) - mean(r_cor_smooth(0.8 * len: len));
                           
%             b1_rcor = cellfun(@(y) linspace(0,1,length(y))'\y',r_cor,'UniformOutput', false);
%             b1_rcor = b1_rcor{1};

            writeToConsole(app, "Working...");

            while  (r_cor_smooth > 0.01)
                ziDAQ('subscribe', ['/' app.device '/demods/'])
                pause(2);
                ziDAQpollData = ziDAQ('poll', 5, 500);
                ziDAQ('unsubscribe', '*');

                data(:,1) = arrayfun(@(x) struct(x.sample), ziDAQpollData.(app.device).demods, 'UniformOutput', false)';

                r_cor(1:4) = cellfun(@(x) sqrt(x.x.^2 + x.y.^2), data(:,1), 'UniformOutput', false);
                lengths = (cellfun('length', r_cor));
                %         [minLength,~] = min(lengths(1:4));


                r_cor([ratioColumns(1) ratioColumns(2)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(1) ratioColumns(2)]))), r_cor([ratioColumns(1) ratioColumns(2)]), 'un', 0);
                r_cor([ratioColumns(3) ratioColumns(4)]) = cellfun(@(x) x(1:min(lengths([ratioColumns(3) ratioColumns(4)]))), r_cor([ratioColumns(3) ratioColumns(4)]), 'un', 0);

                r_cor = cellfun(@rmmissing,r_cor,'UniformOutput', false);
                r_cor = cellfun(@(x) normalize(x,'range'),r_cor,'UniformOutput', false);

                
            r_cor_smooth = cellfun(@(x) smoothdata(x, 'movmedian', 100),r_cor,'UniformOutput', false);
            r_cor_smooth = r_cor_smooth{1};
            r_cor_smooth =  mean(r_cor_smooth(1:300)) - mean(r_cor_smooth(length(r_cor_smooth)-300: length(r_cor_smooth)));


                if r_cor_smooth > 0.01
                    intensity = app.LEDControl_FRET.intensity;
                    numLED = app.LEDControl_FRET.numLED;
                    app.LEDControl_FRET.change_Intensity(numLED, intensity -2);
                    writeToInformationArea(app, append("LED: ",  num2str(numLED)), true);
                    writeToInformationArea(app, append("Intensity: ", num2str(intensity)), true);
                    writeToInformationArea(app, append("Slope: ", num2str(r_cor_smooth)), true);
                end

            end
            intensity = app.LEDControl_FRET.intensity;
            numLED = app.LEDControl_FRET.numLED;
            writeToInformationArea(app, append("LED: ",  num2str(numLED)), true);
            writeToInformationArea(app, append("Intensity: ", num2str(intensity)), true);
            writeToInformationArea(app, append("Slope: ", num2str(r_cor_smooth)), true);
            switchStatusLamp(app, "green");
             writeToConsole(app, "Finished!");


        end

        % Callback function
        function OpenMenuSelected(app, event)
            app.OpenMenu.Enable = false;
            app.LEDControl_FRET = LEDControl_FRET(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FRETControlUIFigure = uifigure('Visible', 'off');
            app.FRETControlUIFigure.Name = 'FRETControl';
            app.FRETControlUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.FRETControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @FRETControlUIFigureCloseRequest, true);
            app.DataMenu = uimenu(app.FRETControlUIFigure);
            app.DataMenu.Text = 'Data';
            app.NewMenu = uimenu(app.DataMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.DataMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.DataMenu);
            app.CloseMenu.Text = 'Close';
            app.SettingsMenu_2 = uimenu(app.FRETControlUIFigure);
            app.SettingsMenu_2.Text = 'Settings';
            app.SettingsMenu = uimenu(app.SettingsMenu_2);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.CheckConnectionMenu = uimenu(app.SettingsMenu_2);
            app.CheckConnectionMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckConnectionMenuSelected, true);
            app.CheckConnectionMenu.Text = 'Check Connection';
            app.SetYAxesMenu = uimenu(app.SettingsMenu_2);
            app.SetYAxesMenu.MenuSelectedFcn = createCallbackFcn(app, @SetYAxesMenuSelected, true);
            app.SetYAxesMenu.Text = 'Set Y-Axes';
            app.GridLayout = uigridlayout(app.FRETControlUIFigure);
            app.PlotArea_1 = uipanel(app.GridLayout);
            app.PlotArea_1.Title = 'Plot 1';
            app.PlotArea_2 = uipanel(app.GridLayout);
            app.PlotArea_2.Title = 'Plot 2';
            app.PlotArea_3 = uipanel(app.GridLayout);
            app.PlotArea_3.Title = 'Plot 3';
            app.PlotArea_4 = uipanel(app.GridLayout);
            app.PlotArea_4.Title = 'Plot 4';
            app.PlotArea_5 = uipanel(app.GridLayout);
            app.PlotArea_5.Title = 'Plot 5';
            app.PlotArea_6 = uipanel(app.GridLayout);
            app.PlotArea_6.Title = 'Plot 6';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Console = uieditfield(app.GridLayout2, 'text');
            app.Console.Editable = 'off';
            app.Lamp = uilamp(app.GridLayout2);
            app.LineH_2 = uilabel(app.GridLayout2);
            app.LineH_2.Text = 'Label3';
            app.GoButton = uibutton(app.GridLayout2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = {'Go'; ''};
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label4 = uilabel(app.GridLayout2);
            app.Label4.Text = 'Label4';
            app.ClearButton = uibutton(app.GridLayout2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', '5', '6'};
            app.ListBox.ItemsData = [1 2 3 4 5 6];
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 5;
            app.DrawButton = uibutton(app.GridLayout2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ClearAllButton = uibutton(app.GridLayout2, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.DrawAllButton = uibutton(app.GridLayout2, 'push');
            app.DrawAllButton.ButtonPushedFcn = createCallbackFcn(app, @DrawAllButtonPushed, true);
            app.DrawAllButton.Text = {'Draw'; 'All'};
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.EditFieldXmin = uieditfield(app.GridLayout5, 'numeric');
            app.EditFieldXmin.ValueDisplayFormat = '%5.5f  s';
            app.EditFieldXmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldXminValueChanged, true);
            app.XminLabel = uilabel(app.GridLayout5);
            app.XminLabel.Text = 'Xmin';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.EditFieldXmax = uieditfield(app.GridLayout6, 'numeric');
            app.EditFieldXmax.ValueDisplayFormat = '%5.5f  s';
            app.EditFieldXmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldXmaxValueChanged, true);
            app.XmaxLabel_2 = uilabel(app.GridLayout6);
            app.XmaxLabel_2.Text = 'Xmax';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.EditFieldYmax = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldYmax.ValueDisplayFormat = '%5.5f  V';
            app.EditFieldYmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldYmaxValueChanged, true);
            app.YmaxLabel = uilabel(app.GridLayout7);
            app.YmaxLabel.Text = 'Ymax';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.EditFieldYmin = uieditfield(app.GridLayout8, 'numeric');
            app.EditFieldYmin.ValueDisplayFormat = '%5.5f  V';
            app.EditFieldYmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldYminValueChanged, true);
            app.YminLabel = uilabel(app.GridLayout8);
            app.YminLabel.Text = 'Ymin';
            app.ResizeButton = uibutton(app.GridLayout2, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ResizeAllButton = uibutton(app.GridLayout2, 'push');
            app.ResizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeAllButtonPushed, true);
            app.ResizeAllButton.Text = 'Resize All';
            app.LineV_1 = uilabel(app.GridLayout);
            app.LineV_1.Text = {''; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MeanSignalIn1Label = uilabel(app.GridLayout3);
            app.MeanSignalIn1Label.Text = 'Mean Plot 1';
            app.EditField_Mean1 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean1.ValueDisplayFormat = '%5.4f';
            app.EditField_Mean1.Editable = 'off';
            app.EditField_2Label_2 = uilabel(app.GridLayout3);
            app.EditField_2Label_2.Text = 'Mean Plot 3';
            app.EditField_Mean3 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean3.ValueDisplayFormat = '%5.8f';
            app.EditField_Mean3.Editable = 'off';
            app.EditField_2Label_3 = uilabel(app.GridLayout3);
            app.EditField_2Label_3.Text = 'Mean Ratio 1';
            app.EditField_Mean5 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean5.ValueDisplayFormat = '%5.5f';
            app.EditField_Mean5.Editable = 'off';
            app.EditField_2Label_4 = uilabel(app.GridLayout3);
            app.EditField_2Label_4.Text = 'Mean Plot 2';
            app.EditField_Mean2 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean2.ValueDisplayFormat = '%5.5f';
            app.EditField_Mean2.Editable = 'off';
            app.EditField_2Label_5 = uilabel(app.GridLayout3);
            app.EditField_2Label_5.Text = 'Mean Plot 4';
            app.EditField_Mean4 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean4.ValueDisplayFormat = '%5.8f';
            app.EditField_Mean4.Editable = 'off';
            app.EditField_2Label_6 = uilabel(app.GridLayout3);
            app.EditField_2Label_6.Text = 'Mean Ratio 2';
            app.EditField_Mean6 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Mean6.ValueDisplayFormat = '%5.5f';
            app.EditField_Mean6.Editable = 'off';
            app.Label5 = uilabel(app.GridLayout3);
            app.Label5.Text = 'Label5';
            app.TimeLabel = uilabel(app.GridLayout3);
            app.TimeLabel.Text = 'Time';
            app.EditField_Time = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Time.ValueDisplayFormat = '%5.1f  s';
            app.EditField_Time.Editable = 'off';
            app.FrequencyLabel = uilabel(app.GridLayout3);
            app.FrequencyLabel.Text = 'Data Frequency';
            app.EditField_DataTime = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_DataTime.ValueDisplayFormat = '%5.1f Hz';
            app.EditField_DataTime.Editable = 'off';
            app.DataPointsLabel = uilabel(app.GridLayout3);
            app.DataPointsLabel.Text = 'DataPoints per Demod';
            app.EditField_MeasPoint = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_MeasPoint.ValueDisplayFormat = '%.0f';
            app.EditField_MeasPoint.Editable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Information_Area = uitextarea(app.GridLayout4);
            app.Information_Area.Placeholder = 'Some Interesting Data Stuff and Notes';
            % Show the figure after all components are created
            app.FRETControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FRET_Software

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FRETControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FRETControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FRETControlUIFigure)
        end
    end
end