classdef Fret_Software < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        Console                    matlab.ui.control.EditField
        Lamp                       matlab.ui.control.Lamp
        TabGroup                   matlab.ui.container.TabGroup
        FRETTab                    matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        FrequencyEditField_2Label  matlab.ui.control.Label
        FrequencyEditFieldFRET     matlab.ui.control.NumericEditField
        IntensitySliderFRET        matlab.ui.control.Slider
        IntensitySpinnerFRET       matlab.ui.control.Spinner
        IntensitySpinner_2Label    matlab.ui.control.Label
        TimePausemsEditFieldFRET   matlab.ui.control.NumericEditField
        TimePauseEditField_2Label  matlab.ui.control.Label
        TimeOnmsEditFieldFRET      matlab.ui.control.NumericEditField
        TimeOnEditField_2Label     matlab.ui.control.Label
        LEDDropDownFRET            matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        MeanRatio                  matlab.ui.control.NumericEditField
        MeanLangwellig             matlab.ui.control.NumericEditField
        MeanKurzwellig             matlab.ui.control.NumericEditField
        MeanRatioLabel             matlab.ui.control.Label
        MeanLangwelligLabel        matlab.ui.control.Label
        MeanKurzwelligLabel        matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        ListBox                    matlab.ui.control.ListBox
        GridLayout4                matlab.ui.container.GridLayout
        YmaxLabel                  matlab.ui.control.Label
        YminLabel                  matlab.ui.control.Label
        XmaxLabel                  matlab.ui.control.Label
        XminLabel                  matlab.ui.control.Label
        EditFieldYmax              matlab.ui.control.NumericEditField
        EditFieldYmin              matlab.ui.control.NumericEditField
        EditFieldXmax              matlab.ui.control.NumericEditField
        EditFieldXmin              matlab.ui.control.NumericEditField
        StopButton                 matlab.ui.control.Button
        GoButton                   matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        PlotArea_3                 matlab.ui.container.Panel
        PlotArea_2                 matlab.ui.container.Panel
        PlotArea_1                 matlab.ui.container.Panel
        LEDTab                     matlab.ui.container.Tab
        GridLayout_2               matlab.ui.container.GridLayout
        Chained_LED_3              matlab.ui.control.DropDown
        DropDown4Label             matlab.ui.control.Label
        Chained_LED_2              matlab.ui.control.DropDown
        DropDown3Label             matlab.ui.control.Label
        Chained_LED_1              matlab.ui.control.DropDown
        DropDown2Label             matlab.ui.control.Label
        LED3Label                  matlab.ui.control.Label
        LED2Label                  matlab.ui.control.Label
        LED1Label                  matlab.ui.control.Label
        FornormalexcitationnotserialLEDSlot1isusedLabel  matlab.ui.control.Label
        SerialButton               matlab.ui.control.StateButton
        Label                      matlab.ui.control.Label
        SerialLEDexcitationLabel   matlab.ui.control.Label
        FrequencyEditFieldLED_3    matlab.ui.control.NumericEditField
        TimePausemsEditFieldLED_3  matlab.ui.control.NumericEditField
        TimeOnmsEditFieldLED_3     matlab.ui.control.NumericEditField
        IntensitySliderLED_3       matlab.ui.control.Slider
        IntensitySpinnerLED_3      matlab.ui.control.Spinner
        LEDDropDownLED_3           matlab.ui.control.DropDown
        PulsedButton               matlab.ui.control.StateButton
        FrequencyEditFieldLED_2    matlab.ui.control.NumericEditField
        TimePausemsEditFieldLED_2  matlab.ui.control.NumericEditField
        TimeOnmsEditFieldLED_2     matlab.ui.control.NumericEditField
        IntensitySliderLED_2       matlab.ui.control.Slider
        IntensitySpinnerLED_2      matlab.ui.control.Spinner
        LEDDropDownLED_2           matlab.ui.control.DropDown
        IntensitySliderLED_1       matlab.ui.control.Slider
        IntensitySpinnerLED_1      matlab.ui.control.Spinner
        IntensitySpinnerLabel      matlab.ui.control.Label
        FrequencyEditFieldLED_1    matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        TimePausemsEditFieldLED_1  matlab.ui.control.NumericEditField
        TimePauseEditFieldLabel    matlab.ui.control.Label
        TimeOnmsEditFieldLED_1     matlab.ui.control.NumericEditField
        TimeOnEditFieldLabel       matlab.ui.control.Label
        StopButtonLED              matlab.ui.control.Button
        GoButtonLED                matlab.ui.control.Button
        LEDDropDownLED_1           matlab.ui.control.DropDown
        LEDDropDownLabel           matlab.ui.control.Label
        SettingsTab                matlab.ui.container.Tab
        GridLayout_3               matlab.ui.container.GridLayout
        MaxCellSizeEditField       matlab.ui.control.NumericEditField
        MaxCellSizeEditFieldLabel  matlab.ui.control.Label
        MarkerRangeLabel           matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        EditFieldmarkerRangeMax    matlab.ui.control.NumericEditField
        EditFieldmarkerRangeMin    matlab.ui.control.NumericEditField
        ComPortEditField           matlab.ui.control.EditField
        ComPortEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        %%Base GUI properties%%
        hAx matlab.graphics.axis.Axes % Axes for animatedlines
        hAnimatedLine matlab.graphics.animation.AnimatedLine % animatedlines


        AxesLimits_Plot = repmat([0 1000 0 5],3,1); % Placeholder for axis size
        selectedList = 1;   %selected Axis for plots

        timedLED = false;   %command for Arduino
        comPort = 'COM7';   %COM port which is connected with arduino

        resolution = 34;    %distance between two individual
        baudRate = 500000;  %speed for communication with arduino

        arduino = 0;            %the connected arduino
        stopMeasurement = false;    %Marker used to stop loop for listening for arduino messages

        analogValues;   %cell to save data from arduino
        measTime;  %cell to save time data
        measurementPlotData;    %cell to save plot data
        pwmChanged = false; % marker to check if pwm Values have been changed
        pwmChangedTag;
    end

    methods (Access = private)

        %update the values XminXmaxYminYmax with Values from the properties
        function updateAxesfields(app,NumAxes)
            NumAxes = app.char2num(NumAxes);
            app.EditFieldXmin.Value = app.AxesLimits_Plot(NumAxes,1);
            app.EditFieldXmax.Value = app.AxesLimits_Plot(NumAxes,2);
            app.EditFieldYmin.Value = app.AxesLimits_Plot(NumAxes,3);
            app.EditFieldYmax.Value = app.AxesLimits_Plot(NumAxes,4);
        end

        %create plot areas
        function [hAx, hAnimatedLine]  = initializePlotAreas(app)
            hAx = createAxes(app);
            hAx = prepareAxes (app,hAx);
            hAnimatedLine = createAnimatedLines(app, hAx);
            transferAxes(app, hAx);
            %arrayfun(@(x) updateAxesLimits(app, x, [0 1000 0 0.00001]), 1:6);
            arrayfun(@(x) updatePlotAxesLimits(app, x),  1:3);
            arrayfun(@(x) updateAxesfields(app, x),  1:3);
            transferAnimatedLines(app, hAnimatedLine);
        end


        %Update the plot axes limits from the app properties
        function updatePlotAxesLimits(app, plotNumber)
            try
                app.hAx(plotNumber).XLim = app.AxesLimits_Plot(plotNumber,1:2);
                app.hAx(plotNumber).YLim = app.AxesLimits_Plot(plotNumber,3:4);
            catch
                writeToConsole(app, "Couldn't set the limits!");
            end
        end

        %create new axes for plots
        function hAx = createAxes(app)
            hAx(1) = axes(app.PlotArea_1);
            hAx(2) = axes(app.PlotArea_2);
            hAx(3) = axes(app.PlotArea_3);

            writeToConsole(app, "Axes were created!");
        end

        %change properties of axes
        function hAx = prepareAxes(app,hAx)
            linkaxes(hAx);
            for i = 1:3
                hAx(i).Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                hAx(i).XAxis.Label.String = "Time (s)";
            end
            for i = 1:2
                hAx(i).YAxis.Label.String = "Intensity (V)";
            end

                hAx(3).YAxis.Label.String = "Ratio";

            %   hAx(1).Toolbar = [];
            setappdata(hAx(1),'LegendColorbarManualSpace',1);
            setappdata(hAx(1),'LegendColorbarReclaimSpace',1);
            linkaxes(hAx, 'off');
            writeToConsole(app, "Axes were prepared!");
        end

        %transfer axes to app properties
        function transferAxes(app, hAx)
            app.hAx(1) = hAx(1);
            app.hAx(2) = hAx(2);
            app.hAx(3) = hAx(3);
            writeToConsole(app, "Axes were copied to app!");
        end

        %create animated lines later used as app property
        function hAnimatedLine = createAnimatedLines(app, hAx)
            hAnimatedLine(1) = animatedline(hAx(1),'MaximumNumPoints',1e8,'Color','b');
            hAnimatedLine(2) = animatedline(hAx(2),'MaximumNumPoints',1e8,'Color','g');
            hAnimatedLine(3) = animatedline(hAx(3),'MaximumNumPoints',1e8);
            writeToConsole(app, "Animated lines were created!");
        end

        %transfer animated lines the app properties
        function transferAnimatedLines(app, hAnimatedLine)
            app.hAnimatedLine(1) = hAnimatedLine(1);
            app.hAnimatedLine(2) = hAnimatedLine(2);
            app.hAnimatedLine(3) = hAnimatedLine(3);
            writeToConsole(app, "AnimatedLines were copied to app!");
        end

        %changes the color of the lamp
        function switchStatusLamp(app, color)
            app.Lamp.Color = color;
        end

        %writes a message to the console field
        function writeToConsole(app, message)
            if isstring(message)
                app.Console.Value = message;
            else
                app.Console.Value = "Message is not a string!";
            end
        end

        %read data send from arduino to serial buffer
        %get data in readable format and fill animated line
        function listenToArduino(app)
            switchStatusLamp(app, 'yellow');
            k = 1;
            app.measTime = zeros(app.MaxCellSizeEditField.Value,6);
            app.analogValues = cell(app.MaxCellSizeEditField.Value,5);
            while app.stopMeasurement == false
                time = read(app.arduino, 10, "uint8");
                analogReads = typecast(uint8([time(9) time(10)]), "uint16") ;
                app.measTime(k,1) = typecast(uint8([time(1) time(2) time(3) time(4)]), "uint32");
                app.measTime(k,2) = typecast(uint8([time(5) time(6) time(7) time(8)]), "uint32");
                app.measTime(k,5) = analogReads + 1;

                analog = read(app.arduino,4*analogReads-1, "uint8");
                analogI = uint8(analog(1:analogReads*2));
                analogI =  swapbytes(typecast(analogI, "uint16"));

                analogII = uint8(analog(analogReads*2+1: analogReads*4-1));

                if(analogII(end) > 3)
                    analogII(analogReads*2) = NaN;
                    analogII =  swapbytes(typecast(analogII, "uint16"));
                    analogII(analogReads) = NaN;
                    app.analogValues{k,1} = analogI';
                    app.analogValues{k,2}= analogII';
                    app.measTime(k,6) = 1;
                    k = k + 1;

                    continue;
                end

                analogII(analogReads*2) = read(app.arduino,1, "uint8");
                analogII =  swapbytes(typecast(analogII, "uint16"));

                app.analogValues{k,1} = analogI';
                app.analogValues{k,2} = analogII';
                app.analogValues{k,3} = app.meas2mean(analogI,app.EditFieldmarkerRangeMin.Value, app.EditFieldmarkerRangeMax.Value);
                app.analogValues{k,4} = app.meas2mean(analogII,app.EditFieldmarkerRangeMin.Value, app.EditFieldmarkerRangeMax.Value);
                app.analogValues{k,5} = app.analogValues{k,4} / app.analogValues{k,3};
                
                addpoints(app.hAnimatedLine(1), app.measTime(k,1)/1000000, (((5 * app.analogValues{k,3})/1023)*2));
                addpoints(app.hAnimatedLine(2), app.measTime(k,1)/1000000, (((5 * app.analogValues{k,4})/1023)*2));
                addpoints(app.hAnimatedLine(3), app.measTime(k,1)/1000000, app.analogValues{k,5});

                if  app.pwmChanged == true
                    changePWM(app);
                end

                if mod(k,10)==1
                    displayMeans(app, app.analogValues{k,3},app.analogValues{k,4},app.analogValues{k,5})
                    drawnow limitrate;
                end
                k = k+1;
            end
            write(app.arduino, 0, "uint8");
            flushArduinoMessages(app);
            readArduinoMessage(app);
            app.stopMeasurement = false;
            calculateExtraTimeData(app, k);
            for i = 1:3
                [app.measurementPlotData(i).time, app.measurementPlotData(i).int] = getpoints(app.hAnimatedLine(i));
            end
            switchStatusLamp(app, 'green');

        end

        %start serial connecntion with arduino
        function connectToArduino(app)
            
            app.arduino = serialport(app.comPort,app.baudRate);
            pause(1);
            configureTerminator(app.arduino,"CR/LF", 10);
            flushArduinoMessages(app);
            writeToConsole(app, "Connected to Arduino");
        end

        function readArduinoMessage(app)
            pause(0.4);
            if app.arduino.NumBytesAvailable > 0
                writeToConsole(app, append("Arduino: ", readline(app.arduino)));
            end
        end

        function flushArduinoMessages(app)
            flush(app.arduino);
        end

        %send data to arduino to start selected measurement
        function startMeasurement(app, mode, timed, chained)
            switch mode
                case "FRET"
                    write(app.arduino, 1, "uint8");
                    readArduinoMessage(app);
                    readArduinoMessage(app);
                    write(app.arduino, app.LEDDropDownFRET.Value, "uint8");
                    write(app.arduino, app.IntensitySpinnerFRET.Value, "uint8");
                    write(app.arduino, app.convertUnit2Micro(app.TimeOnmsEditFieldFRET.Value,"milli"), "uint32");
                    write(app.arduino, app.convertUnit2Micro(app.TimePausemsEditFieldFRET.Value,"milli"), "uint32");
                    readArduinoMessage(app);
                    readArduinoMessage(app);

                    listenToArduino(app);

                case "LED"
                    if ~timed && ~chained
                        write(app.arduino, 2, "uint8");
                        write(app.arduino, app.LEDDropDownLED_1.Value, "uint8");
                        write(app.arduino, app.IntensitySpinnerLED_1.Value, "uint8");
                        write(app.arduino, 0, "uint32");
                        write(app.arduino, 0, "uint32");
                        readArduinoMessage(app);
                        lightIdle(app);
                    end

                    if timed && ~chained
                        write(app.arduino, 3, "uint8");
                        write(app.arduino, app.LEDDropDownLED_1.Value, "uint8");
                        write(app.arduino, app.IntensitySpinnerLED_1.Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.TimeOnmsEditFieldLED_1.Value,"milli"), "uint32");
                        write(app.arduino, app.convertUnit2Micro(app.TimePausemsEditFieldLED_1.Value,"milli"), "uint32");
                        readArduinoMessage(app);
                        lightIdle(app);
                    end

                    if chained && ~timed
                        write(app.arduino, 4, "uint8");
                        app.(append("LEDDropDownLED_",num2str(app.Chained_LED_1.Value))).Value
                        write(app.arduino, app.(append("LEDDropDownLED_",num2str(app.Chained_LED_1.Value))).Value, "uint8");
                        write(app.arduino, app.(append("IntensitySpinnerLED_",num2str(app.Chained_LED_1.Value))).Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldLED_",num2str(app.Chained_LED_1.Value))).Value,"milli"), "uint32");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimePausemsEditFieldLED_",num2str(app.Chained_LED_1.Value))).Value,"milli"), "uint32");
                        readArduinoMessage(app);

                        write(app.arduino, 4, "uint8");
                        app.(append("LEDDropDownLED_",num2str(app.Chained_LED_2.Value))).Value
                        write(app.arduino, app.(append("LEDDropDownLED_",num2str(app.Chained_LED_2.Value))).Value, "uint8");
                        write(app.arduino, app.(append("IntensitySpinnerLED_",num2str(app.Chained_LED_2.Value))).Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldLED_",num2str(app.Chained_LED_2.Value))).Value,"milli"), "uint32");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimePausemsEditFieldLED_",num2str(app.Chained_LED_2.Value))).Value,"milli"), "uint32");
                        readArduinoMessage(app);

                        if app.Chained_LED_3.Value == '0'
                            write(app.arduino, 4, "uint8");
                            app.(append("LEDDropDownLED_",num2str(app.Chained_LED_2.Value))).Value
                            write(app.arduino, app.(append("LEDDropDownLED_",num2str(app.Chained_LED_2.Value))).Value, "uint8");
                            write(app.arduino, app.(append("IntensitySpinnerLED_",num2str(app.Chained_LED_2.Value))).Value, "uint8");
                            write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldLED_",num2str(app.Chained_LED_2.Value))).Value,"milli"), "uint32");
                            write(app.arduino, app.convertUnit2Micro(app.(append("TimePausemsEditFieldLED_",num2str(app.Chained_LED_2.Value))).Value,"milli"), "uint32");
                            readArduinoMessage(app);
                            write(app.arduino, 2, "uint8");

                        else
                            write(app.arduino, 4, "uint8");
                            app.(append("LEDDropDownLED_",num2str(app.Chained_LED_3.Value))).Value
                            write(app.arduino, app.(append("LEDDropDownLED_",num2str(app.Chained_LED_3.Value))).Value, "uint8");
                            write(app.arduino, app.(append("IntensitySpinnerLED_",num2str(app.Chained_LED_3.Value))).Value, "uint8");
                            write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldLED_",num2str(app.Chained_LED_3.Value))).Value,"milli"), "uint32");
                            write(app.arduino, app.convertUnit2Micro(app.(append("TimePausemsEditFieldLED_",num2str(app.Chained_LED_3.Value))).Value,"milli"), "uint32");
                            readArduinoMessage(app);
                            write(app.arduino, 3, "uint8");
                        end
                        readArduinoMessage(app);
                        lightIdle(app);
                    end
                otherwise
                    writeToConsole(app, "Warning unexpected mode")
            end
        end

        %Calculate some extra data for the time
        function calculateExtraTimeData(app, k)
            app.measTime(:,3) =app.measTime(:,2) - app.measTime(:,1);
            j = 2;
            for i = 1:k
                app.measTime(j,4) = app.measTime(j,1) - app.measTime(i,2);
                j = j+ 1;
            end
        end

        %Send data to corresponding editfields
        function displayMeans(app, meanKurz, meanLang, meanRatio)
            app.MeanKurzwellig.Value = (((5 * meanKurz)/1023)*2);
            app.MeanLangwellig.Value = (((5 * meanLang)/1023)*2);
            app.MeanRatio.Value = meanRatio;
        end

        %Send the last changed PWM value to the arduino
        function changePWM(app)
            write(app.arduino, app.(append("IntensitySpinner",app.pwmChangedTag)).Value, "uint8");
            writeToConsole(app, append("PWM changed to ",num2str( app.(append("IntensitySpinner",app.pwmChangedTag)).Value)));
            app.pwmChanged = false;
        end

        function lightIdle(app)
            switchStatusLamp(app, 'yellow');
        %    writeToConsole(app, "light idle");
            while app.stopMeasurement == false
                pause(1);
                if  app.pwmChanged == true
                    changePWM(app);
                end
            end
                write(app.arduino, 0, "uint8");
            app.stopMeasurement = false;
            readArduinoMessage(app);
            switchStatusLamp(app, 'green');
        end


        function clearAnimatedLines(app)
            clearpoints(app.hAnimatedLine(1));
            clearpoints(app.hAnimatedLine(2));
            clearpoints(app.hAnimatedLine(3));
        end
    end

    methods(Static)

        %Convert a char to numeric
        function num = char2num(char)
            if ischar(char)
                char = str2double(char);
            end
            num = char;
        end

        % calculate the mean of a measurement array based on two markers
        function measMean = meas2mean(measurement, markerStart, markerEnd)
            measMean = mean(measurement(markerStart:markerEnd));
        end

        % Save all relevant variables as .mat file
        function saveVariables(measurementPlotData, analogValues, measTime)
            uisave({'measurementPlotData', 'analogValues', 'measTime'}, string(datetime('now','Format','dd.MM.y-HH_mm_ss')));
        end

         function value = convertUnit2Micro(value, unit)
            arguments
                value double
                unit string = ""
            end
            switch unit
                case "milli"
                    value = value * 1000;
                case ""
                    value = value * 1000000;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializePlotAreas(app);
        end

        % Value changed function: TimeOnmsEditFieldFRET, 
        % ...and 3 other components
        function TimeOnmsEditFieldFRETValueChanged(app, event)
            value = event.Value;
            frequency = 1000 /(value + app.(append('TimePausemsEditField', event.Source.Tag)).Value);
            app.(append('FrequencyEditField', event.Source.Tag)).Value = frequency;

        end

        % Value changed function: IntensitySliderFRET, 
        % ...and 3 other components
        function IntensitySliderFRETValueChanged(app, event)
            value = event.Value;
            app.(append('IntensitySlider', event.Source.Tag)).Value  = round(value);
            app.(append('IntensitySpinner', event.Source.Tag)).Value  = round(value);
            app.pwmChanged = true;
            app.pwmChangedTag = event.Source.Tag;

        end

        % Value changed function: IntensitySpinnerFRET, 
        % ...and 3 other components
        function IntensitySpinnerFRETValueChanged(app, event)
            value = event.Value;
            app.(append('IntensitySlider', event.Source.Tag)).Value  = round(value);
            app.pwmChanged = true;
            app.pwmChangedTag = event.Source.Tag;

        end

        % Value changed function: FrequencyEditFieldFRET, 
        % ...and 3 other components
        function FrequencyEditFieldFRETValueChanged(app, event)
            value = event.Value;

            app.(append('TimePausemsEditField', event.Source.Tag)).Value = 1000 / value - app.(append('TimeOnmsEditField', event.Source.Tag)).Value;
        end

        % Value changed function: EditFieldXmin
        function EditFieldXminValueChanged(app, event)
            value = app.EditFieldXmin.Value;
            app.AxesLimits_Plot(app.selectedList,1) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldXmax
        function EditFieldXmaxValueChanged(app, event)
            value = app.EditFieldXmax.Value;
            app.AxesLimits_Plot(app.selectedList,2) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldYmin
        function EditFieldYminValueChanged(app, event)
            value = app.EditFieldYmin.Value;
            app.AxesLimits_Plot(app.selectedList,3) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldYmax
        function EditFieldYmaxValueChanged(app, event)
            value = app.EditFieldYmax.Value;
            app.AxesLimits_Plot(app.selectedList,4) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.char2num(app.ListBox.Value);
            app.selectedList = value;
            updateAxesfields(app, value);
        end

        % Callback function
        function TimedButtonValueChanged(app, event)
            value = app.PulsedButton.Value;
            app.timedLED = value;
        end

        % Value changed function: ComPortEditField
        function ComPortEditFieldValueChanged(app, event)
            value = app.ComPortEditField.Value;
            app.comPort = value;
        end

        % Callback function
        function MaxMeasurementsEditFieldValueChanged(app, event)
            value = app.MaxMeasurementsEditField.Value;
            app.maxMeasurements = value;
        end

        % Callback function
        function ResolutionusEditFieldValueChanged(app, event)
            value = app.ResolutionusEditField.Value;
            app.Resolution = value;
        end

        % Value changed function: TimePausemsEditFieldFRET, 
        % ...and 3 other components
        function TimePausemsEditFieldFRETValueChanged(app, event)
            value = event.Value;
            frequency = 1000 /(value + app.(append('TimeOnmsEditField', event.Source.Tag)).Value);
            app.(append('FrequencyEditField', event.Source.Tag)).Value = frequency;
        end

        % Button pushed function: GoButton, GoButtonLED
        function GoButtonPushed(app, event)
            app.GoButton.Enable = false;
            app.pwmChanged = false;
            timed = app.PulsedButton.Value;
            chained = app.SerialButton.Value;
            tag = event.Source.Tag;
            if class(app.arduino) ~= "internal.Serialport"
                connectToArduino(app);
            end
            flush(app.arduino);
            if app.arduino.NumBytesAvailable > 0
                writeToConsole(app, readline(app.arduino));
            end
            startMeasurement(app,tag, timed, chained);
                        app.GoButton.Enable = true;


        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.saveVariables(app.measurementPlotData, app.analogValues, app.measTime);
            writeToConsole(app, "Data saved!");
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            wb = waitbar(0, 'Loading Data ...','WindowStyle', 'modal');
            wbch = allchild(wb);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1);
            uiopen('*.mat');
            if ~isempty(measurementPlotData)
                app.measurementPlotData = measurementPlotData;
                app.analogValues = analogValues;
                app.measTime = measTime;
                for i = 1:3
                    addpoints(app.hAnimatedLine(i), app.measurementPlotData(i).time, app.measurementPlotData(i).int)
                    drawnow limitrate
                end
                writeToConsole(app, "Data loaded!");
            else
                writeToConsole(app, "Data is empty - not loaded!");
            end
            close(wb);
        end

        % Button pushed function: StopButton, StopButtonLED
        function StopButtonPushed(app, event)
            app.stopMeasurement = true;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            clearAnimatedLines(app);
            app.analogValues = 0;
            app.measTime = 0;
            app.arduino = 0;            
            app.stopMeasurement = false;   
            app.pwmChanged = false; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewMenu = uimenu(app.Menu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FRETTab = uitab(app.TabGroup);
            app.FRETTab.Title = 'FRET';
            app.GridLayout = uigridlayout(app.FRETTab);
            app.PlotArea_1 = uipanel(app.GridLayout);
            app.PlotArea_1.Title = 'Kurzwellig';
            app.PlotArea_2 = uipanel(app.GridLayout);
            app.PlotArea_2.Title = 'Langwellig';
            app.PlotArea_3 = uipanel(app.GridLayout);
            app.PlotArea_3.Title = 'Ratio';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GoButton = uibutton(app.GridLayout2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tag = 'FRET';
            app.GoButton.Text = 'Go';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.EditFieldXmin = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldXmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldXminValueChanged, true);
            app.EditFieldXmax = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldXmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldXmaxValueChanged, true);
            app.EditFieldYmin = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldYmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldYminValueChanged, true);
            app.EditFieldYmax = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldYmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldYmaxValueChanged, true);
            app.XminLabel = uilabel(app.GridLayout4);
            app.XminLabel.Text = 'Xmin';
            app.XmaxLabel = uilabel(app.GridLayout4);
            app.XmaxLabel.Text = 'Xmax';
            app.YminLabel = uilabel(app.GridLayout4);
            app.YminLabel.Text = 'Ymin';
            app.YmaxLabel = uilabel(app.GridLayout4);
            app.YmaxLabel.Text = 'Ymax';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Kurzwellig', 'Langwellig', 'Ratio'};
            app.ListBox.ItemsData = {'1', '2', '3'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '1';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.MeanKurzwelligLabel = uilabel(app.GridLayout5);
            app.MeanKurzwelligLabel.Text = 'Mean Kurzwellig';
            app.MeanLangwelligLabel = uilabel(app.GridLayout5);
            app.MeanLangwelligLabel.Text = 'Mean Langwellig';
            app.MeanRatioLabel = uilabel(app.GridLayout5);
            app.MeanRatioLabel.Text = 'Mean Ratio';
            app.MeanKurzwellig = uieditfield(app.GridLayout5, 'numeric');
            app.MeanLangwellig = uieditfield(app.GridLayout5, 'numeric');
            app.MeanRatio = uieditfield(app.GridLayout5, 'numeric');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.DropDownLabel = uilabel(app.GridLayout6);
            app.DropDownLabel.Text = 'Drop Down';
            app.LEDDropDownFRET = uidropdown(app.GridLayout6);
            app.LEDDropDownFRET.Items = {'LED1', 'LED2', 'LED3'};
            app.LEDDropDownFRET.ItemsData = [1 2 3];
            app.LEDDropDownFRET.Value = 1;
            app.TimeOnEditField_2Label = uilabel(app.GridLayout6);
            app.TimeOnEditField_2Label.Text = 'Time On';
            app.TimeOnmsEditFieldFRET = uieditfield(app.GridLayout6, 'numeric');
            app.TimeOnmsEditFieldFRET.Limits = [3 Inf];
            app.TimeOnmsEditFieldFRET.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldFRET.ValueChangedFcn = createCallbackFcn(app, @TimeOnmsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldFRET.Tag = 'FRET';
            app.TimeOnmsEditFieldFRET.Value = 3;
            app.TimePauseEditField_2Label = uilabel(app.GridLayout6);
            app.TimePauseEditField_2Label.Text = 'Time Pause';
            app.TimePausemsEditFieldFRET = uieditfield(app.GridLayout6, 'numeric');
            app.TimePausemsEditFieldFRET.Limits = [37 Inf];
            app.TimePausemsEditFieldFRET.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldFRET.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimePausemsEditFieldFRET.Tag = 'FRET';
            app.TimePausemsEditFieldFRET.Value = 37;
            app.IntensitySpinner_2Label = uilabel(app.GridLayout6);
            app.IntensitySpinner_2Label.Text = 'Intensity';
            app.IntensitySpinnerFRET = uispinner(app.GridLayout6);
            app.IntensitySpinnerFRET.Limits = [1 255];
            app.IntensitySpinnerFRET.RoundFractionalValues = 'on';
            app.IntensitySpinnerFRET.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerFRET.Tag = 'FRET';
            app.IntensitySpinnerFRET.Value = 1;
            app.IntensitySliderFRET = uislider(app.GridLayout6);
            app.IntensitySliderFRET.Limits = [1 255];
            app.IntensitySliderFRET.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderFRET.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderFRET.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderFRET.Tag = 'FRET';
            app.IntensitySliderFRET.Value = 1;
            app.FrequencyEditFieldFRET = uieditfield(app.GridLayout6, 'numeric');
            app.FrequencyEditFieldFRET.Limits = [0 Inf];
            app.FrequencyEditFieldFRET.ValueDisplayFormat = '%11.4g Hz';
            app.FrequencyEditFieldFRET.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldFRETValueChanged, true);
            app.FrequencyEditFieldFRET.Tag = 'FRET';
            app.FrequencyEditFieldFRET.Value = 25;
            app.FrequencyEditField_2Label = uilabel(app.GridLayout6);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.LEDTab = uitab(app.TabGroup);
            app.LEDTab.Title = 'LED';
            app.GridLayout_2 = uigridlayout(app.LEDTab);
            app.LEDDropDownLabel = uilabel(app.GridLayout_2);
            app.LEDDropDownLabel.Text = 'LED';
            app.LEDDropDownLED_1 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_1.Items = {'LED1', 'LED2', 'LED3'};
            app.LEDDropDownLED_1.ItemsData = [1 2 3];
            app.LEDDropDownLED_1.Tag = 'LED_1';
            app.LEDDropDownLED_1.Value = 1;
            app.GoButtonLED = uibutton(app.GridLayout_2, 'push');
            app.GoButtonLED.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButtonLED.Tag = 'LED';
            app.GoButtonLED.Text = 'Go';
            app.StopButtonLED = uibutton(app.GridLayout_2, 'push');
            app.StopButtonLED.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButtonLED.Text = 'Stop';
            app.TimeOnEditFieldLabel = uilabel(app.GridLayout_2);
            app.TimeOnEditFieldLabel.Text = 'Time On';
            app.TimeOnmsEditFieldLED_1 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_1.Limits = [0.01 Inf];
            app.TimeOnmsEditFieldLED_1.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_1.ValueChangedFcn = createCallbackFcn(app, @TimeOnmsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldLED_1.Tag = 'LED_1';
            app.TimeOnmsEditFieldLED_1.Value = 1;
            app.TimePauseEditFieldLabel = uilabel(app.GridLayout_2);
            app.TimePauseEditFieldLabel.Text = 'Time Pause';
            app.TimePausemsEditFieldLED_1 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_1.Limits = [0.01 Inf];
            app.TimePausemsEditFieldLED_1.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_1.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimePausemsEditFieldLED_1.Tag = 'LED_1';
            app.TimePausemsEditFieldLED_1.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditFieldLED_1 = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyEditFieldLED_1.Limits = [0 Inf];
            app.FrequencyEditFieldLED_1.ValueDisplayFormat = '%11.4g Hz';
            app.FrequencyEditFieldLED_1.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldFRETValueChanged, true);
            app.FrequencyEditFieldLED_1.Tag = 'LED_1';
            app.FrequencyEditFieldLED_1.Value = 500;
            app.IntensitySpinnerLabel = uilabel(app.GridLayout_2);
            app.IntensitySpinnerLabel.Text = 'Intensity';
            app.IntensitySpinnerLED_1 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_1.Limits = [1 255];
            app.IntensitySpinnerLED_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_1.Tag = 'LED_1';
            app.IntensitySpinnerLED_1.Value = 1;
            app.IntensitySliderLED_1 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_1.Limits = [1 255];
            app.IntensitySliderLED_1.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_1.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_1.Tag = 'LED_1';
            app.IntensitySliderLED_1.Value = 1;
            app.LEDDropDownLED_2 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_2.Items = {'LED1', 'LED2', 'LED3'};
            app.LEDDropDownLED_2.ItemsData = [1 2 3];
            app.LEDDropDownLED_2.Tag = 'LED_2';
            app.LEDDropDownLED_2.Value = 2;
            app.IntensitySpinnerLED_2 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_2.Limits = [1 255];
            app.IntensitySpinnerLED_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_2.Tag = 'LED_2';
            app.IntensitySpinnerLED_2.Value = 1;
            app.IntensitySliderLED_2 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_2.Limits = [1 255];
            app.IntensitySliderLED_2.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_2.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_2.Tag = 'LED_2';
            app.IntensitySliderLED_2.Value = 1;
            app.TimeOnmsEditFieldLED_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_2.Limits = [0.01 Inf];
            app.TimeOnmsEditFieldLED_2.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_2.ValueChangedFcn = createCallbackFcn(app, @TimeOnmsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldLED_2.Tag = 'LED_2';
            app.TimeOnmsEditFieldLED_2.Value = 1;
            app.TimePausemsEditFieldLED_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_2.Limits = [0.01 Inf];
            app.TimePausemsEditFieldLED_2.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_2.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimePausemsEditFieldLED_2.Tag = 'LED_2';
            app.TimePausemsEditFieldLED_2.Value = 1;
            app.FrequencyEditFieldLED_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyEditFieldLED_2.Limits = [0 Inf];
            app.FrequencyEditFieldLED_2.ValueDisplayFormat = '%11.4g Hz';
            app.FrequencyEditFieldLED_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldFRETValueChanged, true);
            app.FrequencyEditFieldLED_2.Tag = 'LED_2';
            app.FrequencyEditFieldLED_2.Value = 500;
            app.PulsedButton = uibutton(app.GridLayout_2, 'state');
            app.PulsedButton.Icon = '1382211156.svg';
            app.PulsedButton.Text = 'Pulsed';
            app.LEDDropDownLED_3 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_3.Items = {'LED1', 'LED2', 'LED3'};
            app.LEDDropDownLED_3.ItemsData = [1 2 3];
            app.LEDDropDownLED_3.Tag = 'LED_3';
            app.LEDDropDownLED_3.Value = 3;
            app.IntensitySpinnerLED_3 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_3.Limits = [1 255];
            app.IntensitySpinnerLED_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_3.Tag = 'LED_3';
            app.IntensitySpinnerLED_3.Value = 1;
            app.IntensitySliderLED_3 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_3.Limits = [1 255];
            app.IntensitySliderLED_3.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_3.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_3.Tag = 'LED_3';
            app.IntensitySliderLED_3.Value = 1;
            app.TimeOnmsEditFieldLED_3 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_3.Limits = [0.01 Inf];
            app.TimeOnmsEditFieldLED_3.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_3.ValueChangedFcn = createCallbackFcn(app, @TimeOnmsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldLED_3.Tag = 'LED_3';
            app.TimeOnmsEditFieldLED_3.Value = 1;
            app.TimePausemsEditFieldLED_3 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_3.Limits = [0.01 Inf];
            app.TimePausemsEditFieldLED_3.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_3.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimePausemsEditFieldLED_3.Tag = 'LED_3';
            app.TimePausemsEditFieldLED_3.Value = 1;
            app.FrequencyEditFieldLED_3 = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyEditFieldLED_3.Limits = [0 Inf];
            app.FrequencyEditFieldLED_3.ValueDisplayFormat = '%11.4g Hz';
            app.FrequencyEditFieldLED_3.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldFRETValueChanged, true);
            app.FrequencyEditFieldLED_3.Tag = 'LED_3';
            app.FrequencyEditFieldLED_3.Value = 500;
            app.SerialLEDexcitationLabel = uilabel(app.GridLayout_2);
            app.SerialLEDexcitationLabel.Text = 'Serial LED excitation:';
            app.Label = uilabel(app.GridLayout_2);
            app.SerialButton = uibutton(app.GridLayout_2, 'state');
            app.SerialButton.Icon = 'Rainbow_Of_Light_Emitting_Diodes_clip_art.svg';
            app.SerialButton.Text = 'Serial';
            app.FornormalexcitationnotserialLEDSlot1isusedLabel = uilabel(app.GridLayout_2);
            app.FornormalexcitationnotserialLEDSlot1isusedLabel.Text = {'For normal excitation '; '(not serial) LED '; 'Slot 1 '; 'is used!'};
            app.LED1Label = uilabel(app.GridLayout_2);
            app.LED1Label.Text = 'LED #1';
            app.LED2Label = uilabel(app.GridLayout_2);
            app.LED2Label.Text = 'LED #2';
            app.LED3Label = uilabel(app.GridLayout_2);
            app.LED3Label.Text = 'LED #3';
            app.DropDown2Label = uilabel(app.GridLayout_2);
            app.DropDown2Label.Text = 'Drop Down2';
            app.Chained_LED_1 = uidropdown(app.GridLayout_2);
            app.Chained_LED_1.Items = {'LED #1', 'LED #2', 'LED #3'};
            app.Chained_LED_1.ItemsData = {'1', '2', '3', ''};
            app.Chained_LED_1.Value = '1';
            app.DropDown3Label = uilabel(app.GridLayout_2);
            app.DropDown3Label.Text = 'Drop Down3';
            app.Chained_LED_2 = uidropdown(app.GridLayout_2);
            app.Chained_LED_2.Items = {'LED #1', 'LED #2', 'LED #3'};
            app.Chained_LED_2.ItemsData = {'1', '2', '3'};
            app.Chained_LED_2.Value = '2';
            app.DropDown4Label = uilabel(app.GridLayout_2);
            app.DropDown4Label.Text = 'Drop Down4';
            app.Chained_LED_3 = uidropdown(app.GridLayout_2);
            app.Chained_LED_3.Items = {'LED #1', 'LED #2', 'LED #3', 'None'};
            app.Chained_LED_3.ItemsData = {'1', '2', '3', '0'};
            app.Chained_LED_3.Value = '0';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout_3 = uigridlayout(app.SettingsTab);
            app.ComPortEditFieldLabel = uilabel(app.GridLayout_3);
            app.ComPortEditFieldLabel.Text = 'Com Port';
            app.ComPortEditField = uieditfield(app.GridLayout_3, 'text');
            app.ComPortEditField.ValueChangedFcn = createCallbackFcn(app, @ComPortEditFieldValueChanged, true);
            app.ComPortEditField.Value = 'COM7';
            app.GridLayout7 = uigridlayout(app.GridLayout_3);
            app.EditFieldmarkerRangeMin = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldmarkerRangeMin.ValueDisplayFormat = '%11.4g ';
            app.EditFieldmarkerRangeMin.Value = 40;
            app.EditFieldmarkerRangeMax = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldmarkerRangeMax.Value = 71;
            app.MarkerRangeLabel = uilabel(app.GridLayout_3);
            app.MarkerRangeLabel.Text = 'Marker Range';
            app.MaxCellSizeEditFieldLabel = uilabel(app.GridLayout_3);
            app.MaxCellSizeEditFieldLabel.Text = 'Max Cell Size';
            app.MaxCellSizeEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.MaxCellSizeEditField.ValueDisplayFormat = '%11.7g';
            app.MaxCellSizeEditField.Value = 100000;
            app.Lamp = uilamp(app.UIFigure);
            app.Console = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fret_Software

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end