classdef LEDControl_FRET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LEDControlUIFigure      matlab.ui.Figure
        ArduinoMenu             matlab.ui.container.Menu
        ConnectMenu             matlab.ui.container.Menu
        DisconnectMenu          matlab.ui.container.Menu
        InfoMenu                matlab.ui.container.Menu
        OptionsMenu             matlab.ui.container.Menu
        PinsMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        StatusConnectionPanel   matlab.ui.container.Panel
        ConnectionLabel         matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        FMPanel_8               matlab.ui.container.Panel
        Switch_FM_LED_8         matlab.ui.control.Switch
        Lamp_FM_LED_8           matlab.ui.control.Lamp
        PowerPanel_8            matlab.ui.container.Panel
        Lamp_Power_LED_8        matlab.ui.control.Lamp
        Switch_Power_LED_8      matlab.ui.control.Switch
        Spinner_LED_8           matlab.ui.control.Spinner
        Slider_LED_8            matlab.ui.control.Slider
        Title_LED_8             matlab.ui.control.Label
        FMPanel_7               matlab.ui.container.Panel
        Switch_FM_LED_7         matlab.ui.control.Switch
        Lamp_FM_LED_7           matlab.ui.control.Lamp
        PowerPanel_7            matlab.ui.container.Panel
        Lamp_Power_LED_7        matlab.ui.control.Lamp
        Switch_Power_LED_7      matlab.ui.control.Switch
        Spinner_LED_7           matlab.ui.control.Spinner
        Slider_LED_7            matlab.ui.control.Slider
        Title_LED_7             matlab.ui.control.Label
        FMPanel_6               matlab.ui.container.Panel
        Switch_FM_LED_6         matlab.ui.control.Switch
        Lamp_FM_LED_6           matlab.ui.control.Lamp
        PowerPanel_6            matlab.ui.container.Panel
        Lamp_Power_LED_6        matlab.ui.control.Lamp
        Switch_Power_LED_6      matlab.ui.control.Switch
        Spinner_LED_6           matlab.ui.control.Spinner
        Slider_LED_6            matlab.ui.control.Slider
        Title_LED_6             matlab.ui.control.Label
        FMPanel_5               matlab.ui.container.Panel
        Switch_FM_LED_5         matlab.ui.control.Switch
        Lamp_FM_LED_5           matlab.ui.control.Lamp
        PowerPanel_5            matlab.ui.container.Panel
        Lamp_Power_LED_5        matlab.ui.control.Lamp
        Switch_Power_LED_5      matlab.ui.control.Switch
        Spinner_LED_5           matlab.ui.control.Spinner
        Slider_LED_5            matlab.ui.control.Slider
        Title_LED_5             matlab.ui.control.Label
        FMPanel_4               matlab.ui.container.Panel
        Switch_FM_LED_4         matlab.ui.control.Switch
        Lamp_FM_LED_4           matlab.ui.control.Lamp
        PowerPanel_4            matlab.ui.container.Panel
        Lamp_Power_LED_4        matlab.ui.control.Lamp
        Switch_Power_LED_4      matlab.ui.control.Switch
        Spinner_LED_4           matlab.ui.control.Spinner
        Slider_LED_4            matlab.ui.control.Slider
        Title_LED_4             matlab.ui.control.Label
        FMPanel_3               matlab.ui.container.Panel
        Switch_FM_LED_3         matlab.ui.control.Switch
        Lamp_FM_LED_3           matlab.ui.control.Lamp
        PowerPanel_3            matlab.ui.container.Panel
        Lamp_Power_LED_3        matlab.ui.control.Lamp
        Switch_Power_LED_3      matlab.ui.control.Switch
        Spinner_LED_3           matlab.ui.control.Spinner
        Slider_LED_3            matlab.ui.control.Slider
        Title_LED_3             matlab.ui.control.Label
        FMPanel_2               matlab.ui.container.Panel
        Switch_FM_LED_2         matlab.ui.control.Switch
        Lamp_FM_LED_2           matlab.ui.control.Lamp
        PowerPanel_2            matlab.ui.container.Panel
        Lamp_Power_LED_2        matlab.ui.control.Lamp
        Switch_Power_LED_2      matlab.ui.control.Switch
        Spinner_LED_2           matlab.ui.control.Spinner
        Slider_LED_2            matlab.ui.control.Slider
        Title_LED_2             matlab.ui.control.Label
        Spinner_LED_1           matlab.ui.control.Spinner
        Slider_LED_1            matlab.ui.control.Slider
        Title_LED_1             matlab.ui.control.Label
        FMPanel                 matlab.ui.container.Panel
        Switch_FM_LED_1         matlab.ui.control.Switch
        Lamp_FM_LED_1           matlab.ui.control.Lamp
        PowerPanel              matlab.ui.container.Panel
        Lamp_Power_LED_1        matlab.ui.control.Lamp
        Switch_Power_LED_1      matlab.ui.control.Switch
        ContextMenu_Connection  matlab.ui.container.ContextMenu
        ReconnectMenu           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        connected_arduino % Description
        PinOptions
        ArduinoInfo
        pins_FM
        pins_PWM
        pins_Power
        pins_LTC2465
        FRET_Software
    end

    properties (Access = public)
        intensity
        numLED
    end
    
    
    methods (Access = private)
        
        function switchLamp(app, type, tag)
            
            origin = append(type,"_LED_",num2str(tag));
            lamp_Name = append("Lamp_", origin);
            switch_Name = append("Switch_", origin);
            if (app.(switch_Name).Value) == "On"
                app.(lamp_Name).Color = [0 1 0];
            else
                app.(lamp_Name).Color = [0.8 0.8 0.8];
            end
        end
        
        function dutycyle = calculate_dutycycle(~, square_width)
            
            pwm_bit = 8;
            pwm_resolution = 2^pwm_bit - 1;
            dutycyle = square_width/pwm_resolution;
        end
        
        function change_Arduino_PWM_pin(app, tag, square_width)
            app.intensity = square_width;
            app.numLED = tag;
            dutycycle = calculate_dutycycle(app, square_width);
            try
                pin = app.pins_PWM(tag);
                pin = append("D",pin);
                writePWMDutyCycle(app.connected_arduino, pin, dutycycle);
            catch
                warndlg(["Selected Pin not found";"Check if pins are set correct"])
            end
            
        end
        
        function change_Arduino_Digital_pin(app, tag, tag2, value)
            try
                switch tag2
                    case "Power"
                        pin = app.pins_Power(tag);
                    case "FM"
                        pin = app.pins_FM(tag);
                    case "LTC2465"
                        pin = app.pins_LTC2465(tag);
                end
                
                pin = append("D",pin);
                writeDigitalPin(app.connected_arduino, pin, value);
            catch
                warndlg(["Selected Pin not found";"Check if pins are set correct"])
            end
        end


        function connect_to_Arduino(app)
            
            try
                app.connected_arduino = arduino();
            catch
                warndlg(["Connecting to Arduino failed";"Try to clear any existing connections in the workspace"])
            end
            
            try
                for i = app.pins_FM'
                    configurePin(app.connected_arduino,append("D",i),"DigitalOutput");
                end
                
                for i = unique(app.pins_LTC2465)'
                    configurePin(app.connected_arduino,append("D",i),"DigitalOutput");
                end
                
                for i = app.pins_Power'
                    configurePin(app.connected_arduino,append("D",i),"DigitalOutput");
                end
                
                for i = app.pins_PWM'
                    configurePin(app.connected_arduino,append("D",i),"PWM");
                end
                
                power_LTC2465(app)
            catch
                warndlg(["PIN configuration failend";"Try setting the pins before connecting"])
            end
            
            if class(app.connected_arduino) == "arduino"
                app.Lamp.Color = [0 1 0];
                app.ConnectionLabel.Text = "Connected";
                
            end
        end
        
        
        function  disconnect_Arduino(app)
            
            try
                for i = 2:13
                    configurePin(app.connected_arduino,append("D",num2str(i)),"Unset");
                end
                
                for i = 20:53
                    configurePin(app.connected_arduino,append("D",num2str(i)),"Unset");
                end
            catch
                warning("Couldn't unset pins succesfully");
            end
            
            
            app.connected_arduino = double.empty;
            if class(app.connected_arduino) == "double"
                app.Lamp.Color = [0.8 0.8 0.8];
                app.ConnectionLabel.Text = "Not connected";
            end
        end
        
        function  power_LTC2465(app)
            for i = 1:length(app.pins_LTC2465)
                change_Arduino_Digital_pin(app, i, "LTC2465", 1)
            end
            
        end
        
    end
    
    
    methods (Access = public)
        
        function set_Pingroups(app, PWM_group, power_group, FM_group, LTC2645_group)            
            app.pins_PWM = PWM_group;
            app.pins_Power = power_group;
            app.pins_FM = FM_group;
            app.pins_LTC2465 =  LTC2645_group;
        end

        function change_Intensity(app, numLED, intensity)

            slider_Name = append("Slider_LED_",num2str(numLED));
            app.(slider_Name).Value = intensity;
            spinner_Name = append("Spinner_LED_",num2str(numLED));
            app.(spinner_Name).Value = intensity;
            change_Arduino_PWM_pin(app, numLED, intensity);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.pins_PWM =  ["4" ;"5"; "6"; "7"; "8"; "9"; "10" ;"11"];
            app.pins_Power = ["23";"25";"27";"29";"31";"33";"35";"37"];
            app.pins_FM = ["22";"24";"26";"28";"30";"32";"34";"36"];
            app.pins_LTC2465 =  ["47";"49";"51";"53"];
            app.FRET_Software = parent;
        end

        % Value changed function: Switch_Power_LED_1, Switch_Power_LED_2, 
        % Switch_Power_LED_3, Switch_Power_LED_4, Switch_Power_LED_5, 
        % Switch_Power_LED_6, Switch_Power_LED_7, Switch_Power_LED_8
        function Power_Changed(app, event)
            switchLamp(app, "Power", event.Source.Tag);
            if event.Source.Value == "On"
                value = 1;
            else
                value = 0;
            end
            change_Arduino_Digital_pin(app, str2double(event.Source.Tag), "Power", value)
            
        end

        % Value changed function: Switch_FM_LED_1, Switch_FM_LED_2, 
        % Switch_FM_LED_3, Switch_FM_LED_4, Switch_FM_LED_5, 
        % Switch_FM_LED_6, Switch_FM_LED_7, Switch_FM_LED_8
        function FM_Changed(app, event)
            switchLamp(app, "FM", event.Source.Tag);
            if event.Source.Value == "On"
                value = 1;
            else
                value = 0;
            end
            change_Arduino_Digital_pin(app, str2double(event.Source.Tag), "FM", value)
            
        end

        % Callback function: Slider_LED_5, Spinner_LED_1, Spinner_LED_2, 
        % Spinner_LED_3, Spinner_LED_4, Spinner_LED_5, Spinner_LED_6, 
        % Spinner_LED_7, Spinner_LED_8
        function Spinner_Changed(app, event)
            changingValue = event.Value;
            slider_Name = append("Slider_LED_",num2str(event.Source.Tag));
            app.(slider_Name).Value = changingValue;
            change_Arduino_PWM_pin(app, str2double(event.Source.Tag), changingValue);
            
            
        end

        % Value changed function: Slider_LED_1, Slider_LED_2, 
        % Slider_LED_3, Slider_LED_4, Slider_LED_5, Slider_LED_6, 
        % Slider_LED_7, Slider_LED_8
        function Slider_Changed(app, event)
            changingValue = event.Value;
            spinner_Name = append("Spinner_LED_",num2str(event.Source.Tag));
            app.(spinner_Name).Value = changingValue;
            change_Arduino_PWM_pin(app, str2double(event.Source.Tag), changingValue);
            
        end

        % Menu selected function: ReconnectMenu
        function ReconnectMenuSelected(app, event)
            disconnect_Arduino(app);
            connect_to_Arduino(app);
        end

        % Menu selected function: PinsMenu
        function PinsMenuSelected(app, event)
            app.PinOptions = LEDControl_PinOptions(app, app.pins_PWM, app.pins_FM, app.pins_Power, app.pins_LTC2465);
            
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            connect_to_Arduino(app);
        end

        % Menu selected function: DisconnectMenu
        function DisconnectMenuSelected(app, event)
            disconnect_Arduino(app);
        end

        % Close request function: LEDControlUIFigure
        function LEDControlUIFigureCloseRequest(app, event)
            app.FRET_Software.OpenMenu.Enable = true;
            disconnect_Arduino(app);
            delete(app.ArduinoInfo);
            delete(app.PinOptions);
            delete(app);
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            app.ArduinoInfo = LEDControl_ArduinoInfo(app.connected_arduino);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LEDControlUIFigure = uifigure('Visible', 'off');
            app.LEDControlUIFigure.Name = 'LED Control ';
            app.LEDControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @LEDControlUIFigureCloseRequest, true);
            app.ArduinoMenu = uimenu(app.LEDControlUIFigure);
            app.ArduinoMenu.Text = 'Arduino';
            app.ConnectMenu = uimenu(app.ArduinoMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Text = 'Connect';
            app.DisconnectMenu = uimenu(app.ArduinoMenu);
            app.DisconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectMenuSelected, true);
            app.DisconnectMenu.Text = 'Disconnect';
            app.InfoMenu = uimenu(app.ArduinoMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.OptionsMenu = uimenu(app.LEDControlUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.PinsMenu = uimenu(app.OptionsMenu);
            app.PinsMenu.MenuSelectedFcn = createCallbackFcn(app, @PinsMenuSelected, true);
            app.PinsMenu.Text = 'Pins';
            app.HelpMenu = uimenu(app.LEDControlUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.PowerPanel = uipanel(app.LEDControlUIFigure);
            app.PowerPanel.Title = 'Power';
            app.PowerPanel.Tag = '1';
            app.Switch_Power_LED_1 = uiswitch(app.PowerPanel, 'slider');
            app.Switch_Power_LED_1.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_1.Tag = '1';
            app.Lamp_Power_LED_1 = uilamp(app.PowerPanel);
            app.Lamp_Power_LED_1.Tag = '1';
            app.FMPanel = uipanel(app.LEDControlUIFigure);
            app.FMPanel.Title = 'FM';
            app.FMPanel.Tag = '1';
            app.Lamp_FM_LED_1 = uilamp(app.FMPanel);
            app.Lamp_FM_LED_1.Tag = '1';
            app.Switch_FM_LED_1 = uiswitch(app.FMPanel, 'slider');
            app.Switch_FM_LED_1.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_1.Tag = '1';
            app.Title_LED_1 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_1.Tag = '1';
            app.Title_LED_1.Tooltip = {'Anregungsfilter: F39-340'; '327-353nm'; ''};
            app.Title_LED_1.Text = 'M340L4';
            app.Slider_LED_1 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_1.Limits = [0 255];
            app.Slider_LED_1.MajorTicks = [];
            app.Slider_LED_1.MajorTickLabels = {''};
            app.Slider_LED_1.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_1.MinorTicks = [];
            app.Slider_LED_1.Tag = '1';
            app.Slider_LED_1.Tooltip = {'Filter: F39-340'; '327-353nm'; ''};
            app.Spinner_LED_1 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_1.Limits = [0 255];
            app.Spinner_LED_1.RoundFractionalValues = 'on';
            app.Spinner_LED_1.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_1.Tag = '1';
            app.Spinner_LED_1.Tooltip = {'Filter: F39-340'; '327-353nm'; ''};
            app.Title_LED_2 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_2.Tag = '2';
            app.Title_LED_2.Tooltip = {'Anregungsfilter: F39-371'; '365-375nm'; ''};
            app.Title_LED_2.Text = 'M365LP1';
            app.Slider_LED_2 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_2.Limits = [0 255];
            app.Slider_LED_2.MajorTicks = [];
            app.Slider_LED_2.MajorTickLabels = {''};
            app.Slider_LED_2.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_2.MinorTicks = [];
            app.Slider_LED_2.Tag = '2';
            app.Spinner_LED_2 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_2.Limits = [0 255];
            app.Spinner_LED_2.RoundFractionalValues = 'on';
            app.Spinner_LED_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_2.Tag = '2';
            app.PowerPanel_2 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_2.Title = 'Power';
            app.PowerPanel_2.Tag = '2';
            app.Switch_Power_LED_2 = uiswitch(app.PowerPanel_2, 'slider');
            app.Switch_Power_LED_2.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_2.Tag = '2';
            app.Lamp_Power_LED_2 = uilamp(app.PowerPanel_2);
            app.Lamp_Power_LED_2.Tag = '2';
            app.FMPanel_2 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_2.Title = 'FM';
            app.FMPanel_2.Tag = '2';
            app.Lamp_FM_LED_2 = uilamp(app.FMPanel_2);
            app.Lamp_FM_LED_2.Tag = '2';
            app.Switch_FM_LED_2 = uiswitch(app.FMPanel_2, 'slider');
            app.Switch_FM_LED_2.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_2.Tag = '2';
            app.Title_LED_3 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_3.Tag = '3';
            app.Title_LED_3.Tooltip = {'Anregungsfilter: F49-380'; '370-392nm'; ''};
            app.Title_LED_3.Text = 'M385LP1';
            app.Slider_LED_3 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_3.Limits = [0 255];
            app.Slider_LED_3.MajorTicks = [];
            app.Slider_LED_3.MajorTickLabels = {''};
            app.Slider_LED_3.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_3.MinorTicks = [];
            app.Slider_LED_3.Tag = '3';
            app.Spinner_LED_3 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_3.Limits = [0 255];
            app.Spinner_LED_3.RoundFractionalValues = 'on';
            app.Spinner_LED_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_3.Tag = '3';
            app.PowerPanel_3 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_3.Title = 'Power';
            app.PowerPanel_3.Tag = '3';
            app.Switch_Power_LED_3 = uiswitch(app.PowerPanel_3, 'slider');
            app.Switch_Power_LED_3.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_3.Tag = '3';
            app.Lamp_Power_LED_3 = uilamp(app.PowerPanel_3);
            app.Lamp_Power_LED_3.Tag = '3';
            app.FMPanel_3 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_3.Title = 'FM';
            app.FMPanel_3.Tag = '3';
            app.Lamp_FM_LED_3 = uilamp(app.FMPanel_3);
            app.Lamp_FM_LED_3.Tag = '3';
            app.Switch_FM_LED_3 = uiswitch(app.FMPanel_3, 'slider');
            app.Switch_FM_LED_3.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_3.Tag = '3';
            app.Title_LED_4 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_4.Tag = '4';
            app.Title_LED_4.Tooltip = {'Anregungsfilter: F39-425'; '412-438nm'; ''};
            app.Title_LED_4.Text = 'M430LP1';
            app.Slider_LED_4 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_4.Limits = [0 255];
            app.Slider_LED_4.MajorTicks = [];
            app.Slider_LED_4.MajorTickLabels = {''};
            app.Slider_LED_4.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_4.MinorTicks = [];
            app.Slider_LED_4.Tag = '4';
            app.Slider_LED_4.Tooltip = {''};
            app.Spinner_LED_4 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_4.Limits = [0 255];
            app.Spinner_LED_4.RoundFractionalValues = 'on';
            app.Spinner_LED_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_4.Tag = '4';
            app.Spinner_LED_4.Tooltip = {''};
            app.PowerPanel_4 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_4.Title = 'Power';
            app.PowerPanel_4.Tag = '4';
            app.Switch_Power_LED_4 = uiswitch(app.PowerPanel_4, 'slider');
            app.Switch_Power_LED_4.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_4.Tag = '4';
            app.Lamp_Power_LED_4 = uilamp(app.PowerPanel_4);
            app.Lamp_Power_LED_4.Tag = '4';
            app.FMPanel_4 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_4.Title = 'FM';
            app.FMPanel_4.Tag = '4';
            app.Lamp_FM_LED_4 = uilamp(app.FMPanel_4);
            app.Lamp_FM_LED_4.Tag = '4';
            app.Switch_FM_LED_4 = uiswitch(app.FMPanel_4, 'slider');
            app.Switch_FM_LED_4.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_4.Tag = '4';
            app.Title_LED_5 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_5.Tag = '5';
            app.Title_LED_5.Tooltip = {'Anregungsfilter: F39-478'; '463-487nm'; ''};
            app.Title_LED_5.Text = 'M505L4';
            app.Slider_LED_5 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_5.Limits = [0 255];
            app.Slider_LED_5.MajorTicks = [];
            app.Slider_LED_5.MajorTickLabels = {''};
            app.Slider_LED_5.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Slider_LED_5.MinorTicks = [];
            app.Slider_LED_5.Tag = '5';
            app.Spinner_LED_5 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_5.Limits = [0 255];
            app.Spinner_LED_5.RoundFractionalValues = 'on';
            app.Spinner_LED_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_5.Tag = '5';
            app.PowerPanel_5 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_5.Title = 'Power';
            app.PowerPanel_5.Tag = '5';
            app.Switch_Power_LED_5 = uiswitch(app.PowerPanel_5, 'slider');
            app.Switch_Power_LED_5.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_5.Tag = '5';
            app.Lamp_Power_LED_5 = uilamp(app.PowerPanel_5);
            app.Lamp_Power_LED_5.Tag = '5';
            app.FMPanel_5 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_5.Title = 'FM';
            app.FMPanel_5.Tag = '5';
            app.Lamp_FM_LED_5 = uilamp(app.FMPanel_5);
            app.Lamp_FM_LED_5.Tag = '5';
            app.Switch_FM_LED_5 = uiswitch(app.FMPanel_5, 'slider');
            app.Switch_FM_LED_5.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_5.Tag = '5';
            app.Title_LED_6 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_6.Tag = '6';
            app.Title_LED_6.Tooltip = {'Anregungsfilter: F39-535'; '519-546nm'; ''};
            app.Title_LED_6.Text = 'MINTL5';
            app.Slider_LED_6 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_6.Limits = [0 255];
            app.Slider_LED_6.MajorTicks = [];
            app.Slider_LED_6.MajorTickLabels = {''};
            app.Slider_LED_6.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_6.MinorTicks = [];
            app.Slider_LED_6.Tag = '6';
            app.Spinner_LED_6 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_6.Limits = [0 255];
            app.Spinner_LED_6.RoundFractionalValues = 'on';
            app.Spinner_LED_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_6.Tag = '6';
            app.PowerPanel_6 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_6.Title = 'Power';
            app.PowerPanel_6.Tag = '6';
            app.Switch_Power_LED_6 = uiswitch(app.PowerPanel_6, 'slider');
            app.Switch_Power_LED_6.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_6.Tag = '6';
            app.Lamp_Power_LED_6 = uilamp(app.PowerPanel_6);
            app.Lamp_Power_LED_6.Tag = '6';
            app.FMPanel_6 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_6.Title = 'FM';
            app.FMPanel_6.Tag = '6';
            app.Lamp_FM_LED_6 = uilamp(app.FMPanel_6);
            app.Lamp_FM_LED_6.Tag = '6';
            app.Switch_FM_LED_6 = uiswitch(app.FMPanel_6, 'slider');
            app.Switch_FM_LED_6.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_6.Tag = '6';
            app.Title_LED_7 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_7.Tag = '7';
            app.Title_LED_7.Tooltip = {'Anregungsfilter: F39-577'; '567-589nm'; ''};
            app.Title_LED_7.Text = 'M595L4';
            app.Slider_LED_7 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_7.Limits = [0 255];
            app.Slider_LED_7.MajorTicks = [];
            app.Slider_LED_7.MajorTickLabels = {''};
            app.Slider_LED_7.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_7.MinorTicks = [];
            app.Slider_LED_7.Tag = '7';
            app.Spinner_LED_7 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_7.Limits = [0 255];
            app.Spinner_LED_7.RoundFractionalValues = 'on';
            app.Spinner_LED_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_7.Tag = '7';
            app.PowerPanel_7 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_7.Title = 'Power';
            app.PowerPanel_7.Tag = '7';
            app.Switch_Power_LED_7 = uiswitch(app.PowerPanel_7, 'slider');
            app.Switch_Power_LED_7.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_7.Tag = '7';
            app.Lamp_Power_LED_7 = uilamp(app.PowerPanel_7);
            app.Lamp_Power_LED_7.Tag = '7';
            app.FMPanel_7 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_7.Title = 'FM';
            app.FMPanel_7.Tag = '7';
            app.Lamp_FM_LED_7 = uilamp(app.FMPanel_7);
            app.Lamp_FM_LED_7.Tag = '7';
            app.Switch_FM_LED_7 = uiswitch(app.FMPanel_7, 'slider');
            app.Switch_FM_LED_7.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_7.Tag = '7';
            app.Title_LED_8 = uilabel(app.LEDControlUIFigure);
            app.Title_LED_8.Tag = '8';
            app.Title_LED_8.Tooltip = {'Anregungsfilter: F39-638'; '626-644nm'; ''};
            app.Title_LED_8.Text = 'M617L4';
            app.Slider_LED_8 = uislider(app.LEDControlUIFigure);
            app.Slider_LED_8.Limits = [0 255];
            app.Slider_LED_8.MajorTicks = [];
            app.Slider_LED_8.MajorTickLabels = {''};
            app.Slider_LED_8.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.Slider_LED_8.MinorTicks = [];
            app.Slider_LED_8.Tag = '8';
            app.Spinner_LED_8 = uispinner(app.LEDControlUIFigure);
            app.Spinner_LED_8.Limits = [0 255];
            app.Spinner_LED_8.RoundFractionalValues = 'on';
            app.Spinner_LED_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_Changed, true);
            app.Spinner_LED_8.Tag = '8';
            app.PowerPanel_8 = uipanel(app.LEDControlUIFigure);
            app.PowerPanel_8.Title = 'Power';
            app.PowerPanel_8.Tag = '8';
            app.Switch_Power_LED_8 = uiswitch(app.PowerPanel_8, 'slider');
            app.Switch_Power_LED_8.ValueChangedFcn = createCallbackFcn(app, @Power_Changed, true);
            app.Switch_Power_LED_8.Tag = '8';
            app.Lamp_Power_LED_8 = uilamp(app.PowerPanel_8);
            app.Lamp_Power_LED_8.Tag = '8';
            app.FMPanel_8 = uipanel(app.LEDControlUIFigure);
            app.FMPanel_8.Title = 'FM';
            app.FMPanel_8.Tag = '8';
            app.Lamp_FM_LED_8 = uilamp(app.FMPanel_8);
            app.Lamp_FM_LED_8.Tag = '8';
            app.Switch_FM_LED_8 = uiswitch(app.FMPanel_8, 'slider');
            app.Switch_FM_LED_8.ValueChangedFcn = createCallbackFcn(app, @FM_Changed, true);
            app.Switch_FM_LED_8.Tag = '8';
            app.StatusConnectionPanel = uipanel(app.LEDControlUIFigure);
            app.StatusConnectionPanel.Title = 'Status Connection:';
            app.Lamp = uilamp(app.StatusConnectionPanel);
            app.ConnectionLabel = uilabel(app.StatusConnectionPanel);
            app.ConnectionLabel.Text = 'Not Connected';
            app.ContextMenu_Connection = uicontextmenu(app.LEDControlUIFigure);
            app.ReconnectMenu = uimenu(app.ContextMenu_Connection);
            app.ReconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ReconnectMenuSelected, true);
            app.ReconnectMenu.Text = 'Reconnect';
            % Assign app.ContextMenu_Connection
            app.StatusConnectionPanel.ContextMenu = app.ContextMenu_Connection;
            % Show the figure after all components are created
            app.LEDControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDControl_FRET(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LEDControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LEDControlUIFigure)
        end
    end
end