classdef settings_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        LoadButton                      matlab.ui.control.Button
        SaveasfileButton                matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MFLILockinAmplifierConnectionTab  matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        APILevelEditField               matlab.ui.control.NumericEditField
        APILevelEditFieldLabel          matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        PortEditFieldLabel              matlab.ui.control.Label
        CheckConnectionButton           matlab.ui.control.Button
        IPAdressEditField               matlab.ui.control.EditField
        IPAdressEditFieldLabel          matlab.ui.control.Label
        DeviceIDEditField               matlab.ui.control.EditField
        DeviceIDEditFieldLabel          matlab.ui.control.Label
        DemodulatorsEditField           matlab.ui.control.EditField
        DemodulatorsEditFieldLabel      matlab.ui.control.Label
        SizedataarrayEditField          matlab.ui.control.NumericEditField
        SizedataarrayEditFieldLabel     matlab.ui.control.Label
        MFLILockinAmplifierTab          matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        WavelengthLED4EditField         matlab.ui.control.NumericEditField
        WavelengthLED4EditFieldLabel    matlab.ui.control.Label
        WavelengthLED3EditField         matlab.ui.control.NumericEditField
        WavelengthLED3EditFieldLabel    matlab.ui.control.Label
        WavelengthLED2EditField         matlab.ui.control.NumericEditField
        WavelengthLED2EditFieldLabel    matlab.ui.control.Label
        WavelengthLED1EditField         matlab.ui.control.NumericEditField
        WavelengthLED1EditFieldLabel    matlab.ui.control.Label
        DemodulatorOscillator4DropDown  matlab.ui.control.DropDown
        DemodulatorOscillator4Label     matlab.ui.control.Label
        DemodulatorOscillator3DropDown  matlab.ui.control.DropDown
        DemodulatorOscillator3Label     matlab.ui.control.Label
        DemodulatorOscillator2DropDown  matlab.ui.control.DropDown
        DemodulatorOscillator2DropDownLabel  matlab.ui.control.Label
        DemodulatorOscillator1DropDown  matlab.ui.control.DropDown
        DemodulatorOscillator1DropDownLabel  matlab.ui.control.Label
        Demodulator4InputDropDown       matlab.ui.control.DropDown
        Demodulator4InputDropDownLabel  matlab.ui.control.Label
        Demodulator3InputDropDown       matlab.ui.control.DropDown
        Demodulator3InputDropDownLabel  matlab.ui.control.Label
        Demodulator2InputDropDown       matlab.ui.control.DropDown
        Demodulator2InputDropDownLabel  matlab.ui.control.Label
        Demodulator1InputDropDown       matlab.ui.control.DropDown
        Demodulator1InputDropDownLabel  matlab.ui.control.Label
        TransferRate4EditField          matlab.ui.control.NumericEditField
        TransferRate4EditFieldLabel     matlab.ui.control.Label
        TransferRate3EditField          matlab.ui.control.NumericEditField
        TransferRate3EditFieldLabel     matlab.ui.control.Label
        TransferRate2EditField          matlab.ui.control.NumericEditField
        TransferRate2EditFieldLabel     matlab.ui.control.Label
        TransferRate1EditField          matlab.ui.control.NumericEditField
        TransferRate1EditFieldLabel     matlab.ui.control.Label
        LowPassFilterSpinner_4          matlab.ui.control.Spinner
        LowPassFilterSpinner_3          matlab.ui.control.Spinner
        LowPassFilterSpinner_2          matlab.ui.control.Spinner
        LowPassFilterSpinner            matlab.ui.control.Spinner
        LowPassFilter4EditField         matlab.ui.control.NumericEditField
        LowPassFilter4Label             matlab.ui.control.Label
        LowPassFilter3EditField         matlab.ui.control.NumericEditField
        LowPassFilter3Label             matlab.ui.control.Label
        LowPassFilter2EditField         matlab.ui.control.NumericEditField
        LowPassFilter2Label             matlab.ui.control.Label
        LowPassFilter1EditField         matlab.ui.control.NumericEditField
        LowPassFilter1Label             matlab.ui.control.Label
        FrequencyOscillator4EditField   matlab.ui.control.NumericEditField
        FrequencyOscillator4EditFieldLabel  matlab.ui.control.Label
        FrequencyOscillator3EditField   matlab.ui.control.NumericEditField
        FrequencyOscillator3EditFieldLabel  matlab.ui.control.Label
        FrequencyOscillator2EditField   matlab.ui.control.NumericEditField
        FrequencyOscillator2EditFieldLabel  matlab.ui.control.Label
        FrequencyOscillator1EditField   matlab.ui.control.NumericEditField
        FrequencyOscillator1EditFieldLabel  matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        GridLayout_3                    matlab.ui.container.GridLayout
        Label3_3                        matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4_2                   matlab.ui.container.GridLayout
        Label3_2                        matlab.ui.control.Label
        Ratio2DemodDropDown_2           matlab.ui.control.DropDown
        Ratio2DemodDropDown_1           matlab.ui.control.DropDown
        GridLayout4                     matlab.ui.container.GridLayout
        Label3                          matlab.ui.control.Label
        Ratio1DemodDropDown_2           matlab.ui.control.DropDown
        Ratio1DemodDropDown_1           matlab.ui.control.DropDown
        Ratio2Label                     matlab.ui.control.Label
        Ratio1Label                     matlab.ui.control.Label
        Plot6AxisLimits2                matlab.ui.control.NumericEditField
        Plot5AxisLimits2                matlab.ui.control.NumericEditField
        Plot4AxisLimits2                matlab.ui.control.NumericEditField
        Plot3AxisLimits2                matlab.ui.control.NumericEditField
        Plot6AxisLimits1                matlab.ui.control.NumericEditField
        Plot5AxisLimits1                matlab.ui.control.NumericEditField
        Plot4AxisLimits1                matlab.ui.control.NumericEditField
        Plot3AxisLimits1                matlab.ui.control.NumericEditField
        Plot6AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot6AxisLimitsDropDownLabel    matlab.ui.control.Label
        Plot5AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot5AxisLimitsDropDownLabel    matlab.ui.control.Label
        Plot4AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot4AxisLimitsDropDownLabel    matlab.ui.control.Label
        Plot3AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot3AxisLimitsDropDownLabel    matlab.ui.control.Label
        Plot2AxisLimits2                matlab.ui.control.NumericEditField
        Plot2AxisLimits1                matlab.ui.control.NumericEditField
        Plot2AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot2AxisLimitsDropDownLabel    matlab.ui.control.Label
        Plot1AxisLimits2                matlab.ui.control.NumericEditField
        Plot1AxisLimits1                matlab.ui.control.NumericEditField
        Plot1AxisLimitsDropDown         matlab.ui.control.DropDown
        Plot1AxisLimitsDropDownLabel    matlab.ui.control.Label
        ColorSelector_6                 ColorSelector
        ColorSelector_5                 ColorSelector
        Plot6ColorLabel                 matlab.ui.control.Label
        Plot5ColorLabel                 matlab.ui.control.Label
        Plot4ColorLabel                 matlab.ui.control.Label
        Plot3ColorLabel                 matlab.ui.control.Label
        Plot2ColorLabel                 matlab.ui.control.Label
        Plot1ColorLabel                 matlab.ui.control.Label
        ColorSelector_4                 ColorSelector
        ColorSelector_3                 ColorSelector
        ColorSelector_2                 ColorSelector
        ColorSelector                   ColorSelector
        OtherTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        LowDataModeCheckBox             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        Settings% Description
        axesLimits = struct("x",[], "y", [])
        appName = 'Christians-FRET-Software'
        CallingApp;

    end

    methods (Access = private)

        function fillSettingsStruct(app)
            app.Settings.MFLI_Connection{1} = app.IPAdressEditField.Value;
            app.Settings.MFLI_Connection{2} = app.DeviceIDEditField.Value;
            app.Settings.MFLI_Connection{3} = app.PortEditField.Value;
            app.Settings.MFLI_Connection{4} = app.APILevelEditField.Value;
            app.Settings.MFLI_Connection{5} = sprintf('%d',arrayfun(@str2num, app.DemodulatorsEditField.Value) - 1);
            app.Settings.MFLI_Connection{6} = app.SizedataarrayEditField.Value;

            app.Settings.MFLI_Amplifier{1} = createFrequencyArray(app);
            app.Settings.MFLI_Amplifier{2} = createTransferRateArray(app);
            app.Settings.MFLI_Amplifier{3} = createWaveLengthEDArray(app);
            app.Settings.MFLI_Amplifier{4} = createLowPassFilterStruct(app);
            app.Settings.MFLI_Amplifier{5} = createDemodulatorInputArray(app);
            app.Settings.MFLI_Amplifier{6} = createDemodulatorOsciArray(app);

            app.Settings.Plot{1} = app.axesLimits;
            app.Settings.Plot{2} = createPlotColorArray(app);
            app.Settings.Plot{3} = createRatioDemodsArray_1(app);
            app.Settings.Plot{4} = createRatioDemodsArray_2(app);

            app.Settings.Other{1} = app.LowDataModeCheckBox.Value;
        end

        function fillSettingFields(app)
            app.IPAdressEditField.Value = app.Settings.MFLI_Connection{1};
            app.DeviceIDEditField.Value =  app.Settings.MFLI_Connection{2};
            app.PortEditField.Value = app.Settings.MFLI_Connection{3};
            app.APILevelEditField.Value = app.Settings.MFLI_Connection{4};
            app.DemodulatorsEditField.Value = sprintf('%d',arrayfun(@str2num,  app.Settings.MFLI_Connection{5}) + 1);
            app.SizedataarrayEditField.Value = app.Settings.MFLI_Connection{6};

            fillFrequencyFields(app, app.Settings.MFLI_Amplifier{1});
            fillTransferRateFields(app, app.Settings.MFLI_Amplifier{2});
            fillwaveLengthLEDFields(app, app.Settings.MFLI_Amplifier{3});
            fillLowPassFilterFields(app, app.Settings.MFLI_Amplifier{4});
            fillDemodulatorInputFields(app, app.Settings.MFLI_Amplifier{5});
            fillDemodulatorOsciInputFields(app, app.Settings.MFLI_Amplifier{6});

            fillAxesFields(app);
            fillPlotColorFields(app, app.Settings.Plot{2});
            fillRatioDemodsArray_1(app, app.Settings.Plot{3});
            fillRatioDemodsArray_2(app, app.Settings.Plot{4});

            app.LowDataModeCheckBox.Value = app.Settings.Other{1};
        end


        function frequencyArray = createFrequencyArray(app)
            frequencyArray = ...
                [app.FrequencyOscillator1EditField.Value
                app.FrequencyOscillator2EditField.Value
                app.FrequencyOscillator3EditField.Value
                app.FrequencyOscillator4EditField.Value];
        end

        function fillFrequencyFields(app, frequencyArray)
            app.FrequencyOscillator1EditField.Value = frequencyArray(1);
            app.FrequencyOscillator2EditField.Value = frequencyArray(2);
            app.FrequencyOscillator3EditField.Value = frequencyArray(3);
            app.FrequencyOscillator4EditField.Value = frequencyArray(4);
        end


        function transferRateArray = createTransferRateArray(app)
            transferRateArray = ...
                [app.TransferRate1EditField.Value
                app.TransferRate2EditField.Value
                app.TransferRate3EditField.Value
                app.TransferRate4EditField.Value];
        end
        
        function fillTransferRateFields(app, transferRateArray)
            app.TransferRate1EditField.Value = transferRateArray(1);
            app.TransferRate2EditField.Value = transferRateArray(2);
            app.TransferRate3EditField.Value = transferRateArray(3);
            app.TransferRate4EditField.Value = transferRateArray(4);
        end

        function waveLengthLEDArray = createWaveLengthEDArray(app)
            waveLengthLEDArray = ...
                [app.WavelengthLED1EditField.Value
                app.WavelengthLED2EditField.Value
                app.WavelengthLED3EditField.Value
                app.WavelengthLED4EditField.Value];
        end
        
        function fillwaveLengthLEDFields(app, waveLengthLEDArray)
            app.WavelengthLED1EditField.Value = waveLengthLEDArray(1);
            app.WavelengthLED2EditField.Value = waveLengthLEDArray(2);
            app.WavelengthLED3EditField.Value = waveLengthLEDArray(3);
            app.WavelengthLED4EditField.Value = waveLengthLEDArray(4);
        end


        function lowPassFilterStruct = createLowPassFilterStruct(app)
            lowPassFilterStruct = struct("Bw",[], "Order", []);
            lowPassFilterStruct(1).Bw = app.LowPassFilter1EditField.Value;
            lowPassFilterStruct(2).Bw = app.LowPassFilter2EditField.Value;
            lowPassFilterStruct(3).Bw = app.LowPassFilter3EditField.Value;
            lowPassFilterStruct(4).Bw = app.LowPassFilter4EditField.Value;
            lowPassFilterStruct(1).Order = app.LowPassFilterSpinner.Value;
            lowPassFilterStruct(2).Order = app.LowPassFilterSpinner_2.Value;
            lowPassFilterStruct(3).Order = app.LowPassFilterSpinner_3.Value;
            lowPassFilterStruct(4).Order = app.LowPassFilterSpinner_4.Value;
        end

        function fillLowPassFilterFields(app, lowPassFilterStruct)
            app.LowPassFilter1EditField.Value = lowPassFilterStruct(1).Bw;
            app.LowPassFilter2EditField.Value = lowPassFilterStruct(2).Bw;
            app.LowPassFilter3EditField.Value = lowPassFilterStruct(3).Bw;
            app.LowPassFilter4EditField.Value = lowPassFilterStruct(4).Bw;
            app.LowPassFilterSpinner.Value = lowPassFilterStruct(1).Order;
            app.LowPassFilterSpinner_2.Value = lowPassFilterStruct(2).Order;
            app.LowPassFilterSpinner_3.Value = lowPassFilterStruct(3).Order;
            app.LowPassFilterSpinner_4.Value = lowPassFilterStruct(4).Order;
        end

        function demodulatorInputArray = createDemodulatorInputArray(app)
            demodulatorInputArray = ...
                [app.Demodulator1InputDropDown.Value
                app.Demodulator2InputDropDown.Value
                app.Demodulator3InputDropDown.Value
                app.Demodulator4InputDropDown.Value];
        end

        function fillDemodulatorInputFields(app, demodulatorInputArray)
            app.Demodulator1InputDropDown.Value = num2str(demodulatorInputArray(1));
            app.Demodulator2InputDropDown.Value = num2str(demodulatorInputArray(2));
            app.Demodulator3InputDropDown.Value = num2str(demodulatorInputArray(3));
            app.Demodulator4InputDropDown.Value = num2str(demodulatorInputArray(4));
        end

        function demodulatorOsciArray = createDemodulatorOsciArray(app)
            demodulatorOsciArray = ...
                [app.DemodulatorOscillator1DropDown.Value
                app.DemodulatorOscillator2DropDown.Value
                app.DemodulatorOscillator3DropDown.Value
                app.DemodulatorOscillator4DropDown.Value];
        end

        function fillDemodulatorOsciInputFields(app, demodulatorOsciArray)
            app.DemodulatorOscillator1DropDown.Value = num2str(demodulatorOsciArray(1));
            app.DemodulatorOscillator2DropDown.Value = num2str(demodulatorOsciArray(2));
            app.DemodulatorOscillator3DropDown.Value = num2str(demodulatorOsciArray(3));
            app.DemodulatorOscillator4DropDown.Value = num2str(demodulatorOsciArray(4));
        end


        function fillAxesFields(app)
            app.axesLimits = app.Settings.Plot{1};
            limitsMin = [app.Plot1AxisLimits1 app.Plot2AxisLimits1 app.Plot3AxisLimits1 app.Plot4AxisLimits1 app.Plot5AxisLimits1 app.Plot6AxisLimits1];
            limitsMax = [app.Plot1AxisLimits2 app.Plot2AxisLimits2 app.Plot3AxisLimits2 app.Plot4AxisLimits2 app.Plot5AxisLimits2 app.Plot6AxisLimits2];
            dropdowns = [app.Plot1AxisLimitsDropDown app.Plot2AxisLimitsDropDown app.Plot3AxisLimitsDropDown ...
                app.Plot4AxisLimitsDropDown app.Plot5AxisLimitsDropDown app.Plot6AxisLimitsDropDown];
            for i =1:6
                switch dropdowns(i).Value
                    case "1"
                        limitsMin(i).Value = app.axesLimits(i).x(1);
                        limitsMax(i).Value = app.axesLimits(i).x(2);
                    case "2"
                        limitsMin(i).Value = app.axesLimits(i).y(1);
                        limitsMax(i).Value = app.axesLimits(i).y(2);
                end
            end
        end
        
        function plotColorArray =  createPlotColorArray(app)
            plotColorArray = ...
                [app.ColorSelector.Value, app.ColorSelector_2.Value
                app.ColorSelector_3.Value, app.ColorSelector_4.Value
                app.ColorSelector_5.Value, app.ColorSelector_6.Value];
        end

        function fillPlotColorFields(app, plotColorArray)
            app.ColorSelector.Value = plotColorArray(1,1:3);
            app.ColorSelector_2.Value = plotColorArray(1,4:6);
            app.ColorSelector_3.Value = plotColorArray(2,1:3);
            app.ColorSelector_4.Value = plotColorArray(2,4:6);
            app.ColorSelector_5.Value = plotColorArray(3,1:3);
            app.ColorSelector_6.Value = plotColorArray(3,4:6);
        end

        function ratioDemodsArray_1 = createRatioDemodsArray_1(app)
            ratioDemodsArray_1 = [app.Ratio1DemodDropDown_1.Value app.Ratio1DemodDropDown_2.Value];
        end

        function fillRatioDemodsArray_1(app, ratioDemodsArray_1)
            app.Ratio1DemodDropDown_1.Value = ratioDemodsArray_1(1);
            app.Ratio1DemodDropDown_2.Value = ratioDemodsArray_1(2);
        end

        function ratioDemodsArray_2 = createRatioDemodsArray_2(app)
            ratioDemodsArray_2 = [app.Ratio2DemodDropDown_1.Value app.Ratio2DemodDropDown_2.Value];
        end

        function fillRatioDemodsArray_2(app, ratioDemodsArray_2)
            app.Ratio2DemodDropDown_1.Value = ratioDemodsArray_2(1);
            app.Ratio2DemodDropDown_2.Value = ratioDemodsArray_2(2);
        end


    end

    methods ( Static )

        function Settings = createSettingsStruct()
            MFLI_Connection = cell(1,6);
            MFLI_Amplifier = cell(1,6);
            MFLI_Amplifier{4} = struct("Bw",[], "Order", []);
            Plot = cell(1,4);
            Plot{1} = struct("x",[], "y", []);
            Settings = struct();
            Settings.MFLI_Connection = MFLI_Connection;
            Settings.MFLI_Amplifier = MFLI_Amplifier;
            Settings.Plot = Plot;
            Settings.Other = [];
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, settings)
%            Settings = app.createSettingsStruct();
            app.Settings = settings;
            app.CallingApp  = mainapp;
            fillSettingFields(app)


%             for i = 1:6
%                 app.axesLimits(i).x = [0 0];
%                 app.axesLimits(i).y = [0 0];
%             end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            fillSettingsStruct(app);
            app.CallingApp.Settings = app.Settings;
            app.CallingApp.updateSettings();
            app.CallingApp.SettingsMenu.Enable = true;
            delete(app);
        end

        % Value changed function: Plot1AxisLimits1, Plot1AxisLimits2, 
        % Plot2AxisLimits1, Plot2AxisLimits2, Plot3AxisLimits1, 
        % Plot3AxisLimits2, Plot4AxisLimits1, Plot4AxisLimits2, 
        % Plot5AxisLimits1, Plot5AxisLimits2, Plot6AxisLimits1, 
        % Plot6AxisLimits2
        function PlotAxisLimitsValueChanged(app, event)
            limitsMin = [app.Plot1AxisLimits1 app.Plot2AxisLimits1 app.Plot3AxisLimits1 app.Plot4AxisLimits1 app.Plot5AxisLimits1 app.Plot6AxisLimits1];
            limitsMax = [app.Plot1AxisLimits2 app.Plot2AxisLimits2 app.Plot3AxisLimits2 app.Plot4AxisLimits2 app.Plot5AxisLimits2 app.Plot6AxisLimits2];
            dropdowns = [app.Plot1AxisLimitsDropDown app.Plot2AxisLimitsDropDown app.Plot3AxisLimitsDropDown ...
                        app.Plot4AxisLimitsDropDown app.Plot5AxisLimitsDropDown app.Plot6AxisLimitsDropDown];
            eventTag = str2double(event.Source.Tag(1));
            switch dropdowns(eventTag).Value
                case "1"
                    app.axesLimits(eventTag).x = [limitsMin(eventTag).Value limitsMax(eventTag).Value];
                case "2"
                    app.axesLimits(eventTag).y = [limitsMin(eventTag).Value limitsMax(eventTag).Value];
            end
        end

        % Value changed function: Plot1AxisLimitsDropDown, 
        % Plot2AxisLimitsDropDown, Plot3AxisLimitsDropDown, 
        % Plot4AxisLimitsDropDown, Plot5AxisLimitsDropDown, 
        % Plot6AxisLimitsDropDown
        function PlotAxisLimitsDropDownValueChanged(app, event)
            limitsMin = [app.Plot1AxisLimits1 app.Plot2AxisLimits1 app.Plot3AxisLimits1 app.Plot4AxisLimits1 app.Plot5AxisLimits1 app.Plot6AxisLimits1];
            limitsMax = [app.Plot1AxisLimits2 app.Plot2AxisLimits2 app.Plot3AxisLimits2 app.Plot4AxisLimits2 app.Plot5AxisLimits2 app.Plot6AxisLimits2];
            eventTag = str2double(event.Source.Tag);
            switch event.Source.Value
                case "1"
                    limitsMin(eventTag).Value = app.axesLimits(eventTag).x(1);
                    limitsMax(eventTag).Value = app.axesLimits(eventTag).x(2);
                case "2"
                    limitsMin(eventTag).Value = app.axesLimits(eventTag).y(1);
                    limitsMax(eventTag).Value = app.axesLimits(eventTag).y(2);
            end
        end

        % Button pushed function: SaveasfileButton
        function SaveasfileButtonPushed(app, event)
            fillSettingsStruct(app);
            filePath = app.CallingApp.getStandardFilePath("Settings");
            [fileName, filePath] = uiputfile(append(filePath,"/*.mat"));
            Settings = app.Settings;
            save(fullfile(filePath,fileName), 'Settings');
            app.CallingApp.writeToConsole(append("Successfully saved Settings to ", fullfile(filePath, fileName)));
        end

        % Callback function: CancelButton, UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SettingsMenu.Enable = true;
            delete(app);
        end

        % Button pushed function: CheckConnectionButton
        function CheckConnectionButtonPushed(app, event)
            app.CallingApp.checkConnection(app.IPAdressEditField.Value, app.PortEditField.Value, app.DeviceIDEditField.Value, app.APILevelEditField.Value)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            fillSettingsStruct(app);
            filePath = app.CallingApp.getStandardFilePath("Settings");
            fileName = uigetfile(append(filePath,"/*.mat"));
            Settings = open(fullfile(filePath,fileName));
            names = fieldnames(Settings);
            app.Settings = Settings.(names{1});
            fillSettingFields(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.GridLayout2.Tooltip = {'LED with the same frequency as Oscillator 4'};
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.MFLILockinAmplifierConnectionTab = uitab(app.TabGroup);
            app.MFLILockinAmplifierConnectionTab.Title = 'MFLI Lock-in Amplifier Connection';
            app.GridLayout = uigridlayout(app.MFLILockinAmplifierConnectionTab);
            app.SizedataarrayEditFieldLabel = uilabel(app.GridLayout);
            app.SizedataarrayEditFieldLabel.Text = 'Size data array';
            app.SizedataarrayEditField = uieditfield(app.GridLayout, 'numeric');
            app.DemodulatorsEditFieldLabel = uilabel(app.GridLayout);
            app.DemodulatorsEditFieldLabel.Text = 'Demodulators';
            app.DemodulatorsEditField = uieditfield(app.GridLayout, 'text');
            app.DeviceIDEditFieldLabel = uilabel(app.GridLayout);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.GridLayout, 'text');
            app.IPAdressEditFieldLabel = uilabel(app.GridLayout);
            app.IPAdressEditFieldLabel.Text = 'IP Adress';
            app.IPAdressEditField = uieditfield(app.GridLayout, 'text');
            app.CheckConnectionButton = uibutton(app.GridLayout, 'push');
            app.CheckConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckConnectionButtonPushed, true);
            app.CheckConnectionButton.Text = 'Check Connection';
            app.PortEditFieldLabel = uilabel(app.GridLayout);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.GridLayout, 'numeric');
            app.APILevelEditFieldLabel = uilabel(app.GridLayout);
            app.APILevelEditFieldLabel.Text = 'API Level';
            app.APILevelEditField = uieditfield(app.GridLayout, 'numeric');
            app.MFLILockinAmplifierTab = uitab(app.TabGroup);
            app.MFLILockinAmplifierTab.Title = 'MFLI Lock-in Amplifier';
            app.GridLayout_2 = uigridlayout(app.MFLILockinAmplifierTab);
            app.FrequencyOscillator1EditFieldLabel = uilabel(app.GridLayout_2);
            app.FrequencyOscillator1EditFieldLabel.Text = 'Frequency Oscillator 1';
            app.FrequencyOscillator1EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyOscillator1EditField.ValueDisplayFormat = '%.1f Hz';
            app.FrequencyOscillator2EditFieldLabel = uilabel(app.GridLayout_2);
            app.FrequencyOscillator2EditFieldLabel.Text = 'Frequency Oscillator 2';
            app.FrequencyOscillator2EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyOscillator2EditField.ValueDisplayFormat = '%.1f Hz';
            app.FrequencyOscillator3EditFieldLabel = uilabel(app.GridLayout_2);
            app.FrequencyOscillator3EditFieldLabel.Text = 'Frequency Oscillator 3';
            app.FrequencyOscillator3EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyOscillator3EditField.ValueDisplayFormat = '%.1f Hz';
            app.FrequencyOscillator4EditFieldLabel = uilabel(app.GridLayout_2);
            app.FrequencyOscillator4EditFieldLabel.Text = 'Frequency Oscillator 4';
            app.FrequencyOscillator4EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FrequencyOscillator4EditField.ValueDisplayFormat = '%.1f Hz';
            app.LowPassFilter1Label = uilabel(app.GridLayout_2);
            app.LowPassFilter1Label.Text = 'Low-Pass Filter 1';
            app.LowPassFilter1EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.LowPassFilter1EditField.ValueDisplayFormat = '%.1f dB';
            app.LowPassFilter2Label = uilabel(app.GridLayout_2);
            app.LowPassFilter2Label.Text = 'Low-Pass Filter 2';
            app.LowPassFilter2EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.LowPassFilter2EditField.ValueDisplayFormat = '%.1f dB';
            app.LowPassFilter3Label = uilabel(app.GridLayout_2);
            app.LowPassFilter3Label.Text = 'Low-Pass Filter 3';
            app.LowPassFilter3EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.LowPassFilter3EditField.ValueDisplayFormat = '%.1f dB';
            app.LowPassFilter4Label = uilabel(app.GridLayout_2);
            app.LowPassFilter4Label.Text = 'Low-Pass Filter 4';
            app.LowPassFilter4EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.LowPassFilter4EditField.ValueDisplayFormat = '%.1f dB';
            app.LowPassFilterSpinner = uispinner(app.GridLayout_2);
            app.LowPassFilterSpinner.Limits = [1 8];
            app.LowPassFilterSpinner.RoundFractionalValues = 'on';
            app.LowPassFilterSpinner.Value = 1;
            app.LowPassFilterSpinner_2 = uispinner(app.GridLayout_2);
            app.LowPassFilterSpinner_2.Limits = [1 8];
            app.LowPassFilterSpinner_2.RoundFractionalValues = 'on';
            app.LowPassFilterSpinner_2.Value = 1;
            app.LowPassFilterSpinner_3 = uispinner(app.GridLayout_2);
            app.LowPassFilterSpinner_3.Limits = [1 8];
            app.LowPassFilterSpinner_3.RoundFractionalValues = 'on';
            app.LowPassFilterSpinner_3.Value = 1;
            app.LowPassFilterSpinner_4 = uispinner(app.GridLayout_2);
            app.LowPassFilterSpinner_4.Limits = [1 8];
            app.LowPassFilterSpinner_4.RoundFractionalValues = 'on';
            app.LowPassFilterSpinner_4.Value = 1;
            app.TransferRate1EditFieldLabel = uilabel(app.GridLayout_2);
            app.TransferRate1EditFieldLabel.Text = 'Transfer Rate 1';
            app.TransferRate1EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TransferRate1EditField.ValueDisplayFormat = '%.1f';
            app.TransferRate2EditFieldLabel = uilabel(app.GridLayout_2);
            app.TransferRate2EditFieldLabel.Text = 'Transfer Rate 2';
            app.TransferRate2EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TransferRate2EditField.ValueDisplayFormat = '%.1f';
            app.TransferRate3EditFieldLabel = uilabel(app.GridLayout_2);
            app.TransferRate3EditFieldLabel.Text = 'Transfer Rate 3';
            app.TransferRate3EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TransferRate3EditField.ValueDisplayFormat = '%.1f';
            app.TransferRate4EditFieldLabel = uilabel(app.GridLayout_2);
            app.TransferRate4EditFieldLabel.Text = 'Transfer Rate 4';
            app.TransferRate4EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TransferRate4EditField.ValueDisplayFormat = '%.1f';
            app.Demodulator1InputDropDownLabel = uilabel(app.GridLayout_2);
            app.Demodulator1InputDropDownLabel.Text = 'Demodulator 1 Input';
            app.Demodulator1InputDropDown = uidropdown(app.GridLayout_2);
            app.Demodulator1InputDropDown.Items = {'SignalIn 1', 'CurrentIn 1', ''};
            app.Demodulator1InputDropDown.ItemsData = {'0', '1'};
            app.Demodulator1InputDropDown.Value = '0';
            app.Demodulator2InputDropDownLabel = uilabel(app.GridLayout_2);
            app.Demodulator2InputDropDownLabel.Text = 'Demodulator 2 Input';
            app.Demodulator2InputDropDown = uidropdown(app.GridLayout_2);
            app.Demodulator2InputDropDown.Items = {'SignalIn 1', 'CurrentIn 1'};
            app.Demodulator2InputDropDown.ItemsData = {'0', '1'};
            app.Demodulator2InputDropDown.Value = '1';
            app.Demodulator3InputDropDownLabel = uilabel(app.GridLayout_2);
            app.Demodulator3InputDropDownLabel.Text = 'Demodulator 3 Input';
            app.Demodulator3InputDropDown = uidropdown(app.GridLayout_2);
            app.Demodulator3InputDropDown.Items = {'SignalIn 1', 'CurrentIn 1', ''};
            app.Demodulator3InputDropDown.ItemsData = {'0', '1'};
            app.Demodulator3InputDropDown.Value = '0';
            app.Demodulator4InputDropDownLabel = uilabel(app.GridLayout_2);
            app.Demodulator4InputDropDownLabel.Text = 'Demodulator 4 Input';
            app.Demodulator4InputDropDown = uidropdown(app.GridLayout_2);
            app.Demodulator4InputDropDown.Items = {'SignalIn 1', 'CurrentIn 1', ''};
            app.Demodulator4InputDropDown.ItemsData = {'0', '1'};
            app.Demodulator4InputDropDown.Value = '1';
            app.DemodulatorOscillator1DropDownLabel = uilabel(app.GridLayout_2);
            app.DemodulatorOscillator1DropDownLabel.Text = 'Demodulator Oscillator 1';
            app.DemodulatorOscillator1DropDown = uidropdown(app.GridLayout_2);
            app.DemodulatorOscillator1DropDown.Items = {'Oscillator 1', 'Oscillator 2', 'Oscillator 3', 'Oscillator 4'};
            app.DemodulatorOscillator1DropDown.ItemsData = {'0', '1', '2', '3'};
            app.DemodulatorOscillator1DropDown.Value = '0';
            app.DemodulatorOscillator2DropDownLabel = uilabel(app.GridLayout_2);
            app.DemodulatorOscillator2DropDownLabel.Text = 'Demodulator Oscillator 2';
            app.DemodulatorOscillator2DropDown = uidropdown(app.GridLayout_2);
            app.DemodulatorOscillator2DropDown.Items = {'Oscillator 1', 'Oscillator 2', 'Oscillator 3', 'Oscillator 4'};
            app.DemodulatorOscillator2DropDown.ItemsData = {'0', '1', '2', '3'};
            app.DemodulatorOscillator2DropDown.Value = '1';
            app.DemodulatorOscillator3Label = uilabel(app.GridLayout_2);
            app.DemodulatorOscillator3Label.Text = 'Demodulator Oscillator 3';
            app.DemodulatorOscillator3DropDown = uidropdown(app.GridLayout_2);
            app.DemodulatorOscillator3DropDown.Items = {'Oscillator 1', 'Oscillator 2', 'Oscillator 3', 'Oscillator 4'};
            app.DemodulatorOscillator3DropDown.ItemsData = {'0', '1', '2', '3'};
            app.DemodulatorOscillator3DropDown.Value = '2';
            app.DemodulatorOscillator4Label = uilabel(app.GridLayout_2);
            app.DemodulatorOscillator4Label.Text = 'Demodulator Oscillator 4';
            app.DemodulatorOscillator4DropDown = uidropdown(app.GridLayout_2);
            app.DemodulatorOscillator4DropDown.Items = {'Oscillator 1', 'Oscillator 2', 'Oscillator 3', 'Oscillator 4'};
            app.DemodulatorOscillator4DropDown.ItemsData = {'0', '1', '2', '3'};
            app.DemodulatorOscillator4DropDown.Value = '3';
            app.WavelengthLED1EditFieldLabel = uilabel(app.GridLayout_2);
            app.WavelengthLED1EditFieldLabel.Tooltip = {'LED with the same frequency as Oscillator 1'};
            app.WavelengthLED1EditFieldLabel.Text = 'Wavelength LED 1';
            app.WavelengthLED1EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.WavelengthLED1EditField.ValueDisplayFormat = '%.0f nm';
            app.WavelengthLED1EditField.Tooltip = {'LED with the same frequency as Oscillator 1'};
            app.WavelengthLED2EditFieldLabel = uilabel(app.GridLayout_2);
            app.WavelengthLED2EditFieldLabel.Tooltip = {'LED with the same frequency as Oscillator 2'};
            app.WavelengthLED2EditFieldLabel.Text = 'Wavelength LED 2';
            app.WavelengthLED2EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.WavelengthLED2EditField.ValueDisplayFormat = '%.0f nm';
            app.WavelengthLED2EditField.Tooltip = {'LED with the same frequency as Oscillator 2'};
            app.WavelengthLED3EditFieldLabel = uilabel(app.GridLayout_2);
            app.WavelengthLED3EditFieldLabel.Tooltip = {'LED with the same frequency as Oscillator 3'};
            app.WavelengthLED3EditFieldLabel.Text = 'Wavelength LED 3';
            app.WavelengthLED3EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.WavelengthLED3EditField.ValueDisplayFormat = '%.0f nm';
            app.WavelengthLED3EditField.Tooltip = {'LED with the same frequency as Oscillator 3'};
            app.WavelengthLED4EditFieldLabel = uilabel(app.GridLayout_2);
            app.WavelengthLED4EditFieldLabel.Tooltip = {'LED with the same frequency as Oscillator 4'};
            app.WavelengthLED4EditFieldLabel.Text = 'Wavelength LED 4';
            app.WavelengthLED4EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.WavelengthLED4EditField.ValueDisplayFormat = '%.0f nm';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout_3 = uigridlayout(app.PlotsTab);
            app.ColorSelector = ColorSelector(app.GridLayout_3);
            app.ColorSelector_2 = ColorSelector(app.GridLayout_3);
            app.ColorSelector_3 = ColorSelector(app.GridLayout_3);
            app.ColorSelector_4 = ColorSelector(app.GridLayout_3);
            app.Plot1ColorLabel = uilabel(app.GridLayout_3);
            app.Plot1ColorLabel.Text = 'Plot 1 Color';
            app.Plot2ColorLabel = uilabel(app.GridLayout_3);
            app.Plot2ColorLabel.Text = 'Plot 2 Color';
            app.Plot3ColorLabel = uilabel(app.GridLayout_3);
            app.Plot3ColorLabel.Text = 'Plot 3 Color';
            app.Plot4ColorLabel = uilabel(app.GridLayout_3);
            app.Plot4ColorLabel.Text = 'Plot 4 Color';
            app.Plot5ColorLabel = uilabel(app.GridLayout_3);
            app.Plot5ColorLabel.Text = 'Plot 5 Color';
            app.Plot6ColorLabel = uilabel(app.GridLayout_3);
            app.Plot6ColorLabel.Text = 'Plot 6 Color';
            app.ColorSelector_5 = ColorSelector(app.GridLayout_3);
            app.ColorSelector_6 = ColorSelector(app.GridLayout_3);
            app.Plot1AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot1AxisLimitsDropDownLabel.Text = 'Plot 1 Axis Limits';
            app.Plot1AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot1AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot1AxisLimitsDropDown.ItemsData = {'1', '2'};
            app.Plot1AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot1AxisLimitsDropDown.Tag = '1';
            app.Plot1AxisLimitsDropDown.Value = '1';
            app.Plot1AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot1AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot1AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot1AxisLimits1.Tag = '11';
            app.Plot1AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot1AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot1AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot1AxisLimits2.Tag = '12';
            app.Plot2AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot2AxisLimitsDropDownLabel.Text = 'Plot 2 Axis Limits';
            app.Plot2AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot2AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot2AxisLimitsDropDown.ItemsData = {'1', '2'};
            app.Plot2AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot2AxisLimitsDropDown.Tag = '2';
            app.Plot2AxisLimitsDropDown.Value = '1';
            app.Plot2AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot2AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot2AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot2AxisLimits1.Tag = '21';
            app.Plot2AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot2AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot2AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot2AxisLimits2.Tag = '22';
            app.Plot3AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot3AxisLimitsDropDownLabel.Text = 'Plot 3 Axis Limits';
            app.Plot3AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot3AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot3AxisLimitsDropDown.ItemsData = {'1', '2'};
            app.Plot3AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot3AxisLimitsDropDown.Tag = '3';
            app.Plot3AxisLimitsDropDown.Value = '1';
            app.Plot4AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot4AxisLimitsDropDownLabel.Text = 'Plot 4 Axis Limits';
            app.Plot4AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot4AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot4AxisLimitsDropDown.ItemsData = {'1', '2', ''};
            app.Plot4AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot4AxisLimitsDropDown.Tag = '4';
            app.Plot4AxisLimitsDropDown.Value = '1';
            app.Plot5AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot5AxisLimitsDropDownLabel.Text = 'Plot 5 Axis Limits';
            app.Plot5AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot5AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot5AxisLimitsDropDown.ItemsData = {'1', '2'};
            app.Plot5AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot5AxisLimitsDropDown.Tag = '5';
            app.Plot5AxisLimitsDropDown.Value = '1';
            app.Plot6AxisLimitsDropDownLabel = uilabel(app.GridLayout_3);
            app.Plot6AxisLimitsDropDownLabel.Text = 'Plot 6 Axis Limits';
            app.Plot6AxisLimitsDropDown = uidropdown(app.GridLayout_3);
            app.Plot6AxisLimitsDropDown.Items = {'X', 'Y'};
            app.Plot6AxisLimitsDropDown.ItemsData = {'1', '2'};
            app.Plot6AxisLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsDropDownValueChanged, true);
            app.Plot6AxisLimitsDropDown.Tag = '6';
            app.Plot6AxisLimitsDropDown.Value = '1';
            app.Plot3AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot3AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot3AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot3AxisLimits1.Tag = '31';
            app.Plot4AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot4AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot4AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot4AxisLimits1.Tag = '41';
            app.Plot5AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot5AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot5AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot5AxisLimits1.Tag = '51';
            app.Plot6AxisLimits1 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot6AxisLimits1.ValueDisplayFormat = '%5.5f';
            app.Plot6AxisLimits1.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot6AxisLimits1.Tag = '61';
            app.Plot3AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot3AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot3AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot3AxisLimits2.Tag = '32';
            app.Plot4AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot4AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot4AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot4AxisLimits2.Tag = '42';
            app.Plot5AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot5AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot5AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot5AxisLimits2.Tag = '52';
            app.Plot6AxisLimits2 = uieditfield(app.GridLayout_3, 'numeric');
            app.Plot6AxisLimits2.ValueDisplayFormat = '%5.5f';
            app.Plot6AxisLimits2.ValueChangedFcn = createCallbackFcn(app, @PlotAxisLimitsValueChanged, true);
            app.Plot6AxisLimits2.Tag = '62';
            app.GridLayout3 = uigridlayout(app.GridLayout_3);
            app.Ratio1Label = uilabel(app.GridLayout3);
            app.Ratio1Label.Text = 'Ratio 1';
            app.Ratio2Label = uilabel(app.GridLayout3);
            app.Ratio2Label.Text = 'Ratio 2';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Ratio1DemodDropDown_1 = uidropdown(app.GridLayout4);
            app.Ratio1DemodDropDown_1.Items = {'Demodulator 1', 'Demodulator 2', 'Demodulator 3', 'Demodulator 4'};
            app.Ratio1DemodDropDown_1.ItemsData = {'1', '2', '3', '4', ''};
            app.Ratio1DemodDropDown_1.Value = '1';
            app.Ratio1DemodDropDown_2 = uidropdown(app.GridLayout4);
            app.Ratio1DemodDropDown_2.Items = {'Demodulator 1', 'Demodulator 2', 'Demodulator 3', 'Demodulator 4'};
            app.Ratio1DemodDropDown_2.ItemsData = {'1', '2', '3', '4', ''};
            app.Ratio1DemodDropDown_2.Value = '1';
            app.Label3 = uilabel(app.GridLayout4);
            app.Label3.Text = 'Label3';
            app.GridLayout4_2 = uigridlayout(app.GridLayout3);
            app.Ratio2DemodDropDown_1 = uidropdown(app.GridLayout4_2);
            app.Ratio2DemodDropDown_1.Items = {'Demodulator 1', 'Demodulator 2', 'Demodulator 3', 'Demodulator 4'};
            app.Ratio2DemodDropDown_1.ItemsData = {'1', '2', '3', '4', ''};
            app.Ratio2DemodDropDown_1.Value = '1';
            app.Ratio2DemodDropDown_2 = uidropdown(app.GridLayout4_2);
            app.Ratio2DemodDropDown_2.Items = {'Demodulator 1', 'Demodulator 2', 'Demodulator 3', 'Demodulator 4'};
            app.Ratio2DemodDropDown_2.ItemsData = {'1', '2', '3', '4', ''};
            app.Ratio2DemodDropDown_2.Value = '1';
            app.Label3_2 = uilabel(app.GridLayout4_2);
            app.Label3_2.Text = 'Label3';
            app.Label3_3 = uilabel(app.GridLayout_3);
            app.Label3_3.Text = 'Label3';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.GridLayout5 = uigridlayout(app.OtherTab);
            app.LowDataModeCheckBox = uicheckbox(app.GridLayout5);
            app.LowDataModeCheckBox.Text = 'Low Data Mode';
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveasfileButton = uibutton(app.GridLayout2, 'push');
            app.SaveasfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasfileButtonPushed, true);
            app.SaveasfileButton.Text = 'Save as file';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end